classdef Copy_of_aplicativo_pendulo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        SimulaoTab                     matlab.ui.container.Tab
        mostrar_janela                 matlab.ui.control.CheckBox
        btn_visualizar_ganho_kalman    matlab.ui.control.Button
        PoloseZerosButton              matlab.ui.control.Button
        ConfigurodoTempoPanel          matlab.ui.container.Panel
        periodo_amostragem             matlab.ui.control.NumericEditField
        PerododeAmostragemLabel        matlab.ui.control.Label
        tempo_simulacao                matlab.ui.control.Spinner
        TempodeSimulaoLabel            matlab.ui.control.Label
        FiltragemDropDownLabel         matlab.ui.control.Label
        dropbox_filtragem              matlab.ui.control.DropDown
        CondiesIniciaisSistemaRealPanel  matlab.ui.container.Panel
        ci_x                           matlab.ui.control.NumericEditField
        VelocidadedoCarrinhoEditFieldLabel_2  matlab.ui.control.Label
        ci_dotx                        matlab.ui.control.NumericEditField
        ngulodaHasteEditFieldLabel_2   matlab.ui.control.Label
        ci_alpha                       matlab.ui.control.NumericEditField
        VelocidadedaHasteEditFieldLabel_2  matlab.ui.control.Label
        ci_dotalpha                    matlab.ui.control.NumericEditField
        PosiodoCarrinhoEditFieldLabel_2  matlab.ui.control.Label
        panel_estimador                matlab.ui.container.Panel
        ci_estimador_x_label           matlab.ui.control.Label
        ci_estimador_dotalpha          matlab.ui.control.NumericEditField
        ci_estimador_dotalpha_label    matlab.ui.control.Label
        ci_estimador_alpha             matlab.ui.control.NumericEditField
        ci_estimador_alpha_label       matlab.ui.control.Label
        ci_estimador_dotx              matlab.ui.control.NumericEditField
        ci_estimador_dotx_label        matlab.ui.control.Label
        ci_estimador_x                 matlab.ui.control.NumericEditField
        panel_linearizacao             matlab.ui.container.ButtonGroup
        checkbox_nao_linear            matlab.ui.control.CheckBox
        checkbox_linearizado           matlab.ui.control.CheckBox
        checkbox_incluir_controle      matlab.ui.control.CheckBox
        panel_filtragem                matlab.ui.container.ButtonGroup
        radio_filtro_kalman_estendido  matlab.ui.control.RadioButton
        radio_filtro_kalman            matlab.ui.control.RadioButton
        panel_matriz_q                 matlab.ui.container.Panel
        dotx                           matlab.ui.control.NumericEditField
        VelocidadedoCarrinhoEditFieldLabel  matlab.ui.control.Label
        alpha                          matlab.ui.control.NumericEditField
        ngulodaHasteEditFieldLabel     matlab.ui.control.Label
        x                              matlab.ui.control.NumericEditField
        PosiodoCarrinhoEditFieldLabel  matlab.ui.control.Label
        dotalpha                       matlab.ui.control.NumericEditField
        VelocidadedaHasteEditFieldLabel  matlab.ui.control.Label
        r                              matlab.ui.control.Spinner
        RSpinnerLabel                  matlab.ui.control.Label
        panel_ganho_k                  matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        k4                             matlab.ui.control.Label
        k3                             matlab.ui.control.Label
        k2                             matlab.ui.control.Label
        k1                             matlab.ui.control.Label
        RudonosistemawPanel            matlab.ui.container.Panel
        incluir_ruido_sistema          matlab.ui.control.CheckBox
        simulacao_variancia_w          matlab.ui.control.NumericEditField
        simulacao_variancia_label_w    matlab.ui.control.Label
        simulacao_media_w              matlab.ui.control.Spinner
        simulacao_media_label_w        matlab.ui.control.Label
        RudonosensorwPanel             matlab.ui.container.Panel
        simulacao_variancia_label_v    matlab.ui.control.Label
        simulacao_variancia_v          matlab.ui.control.NumericEditField
        incluir_ruido_sensor           matlab.ui.control.CheckBox
        simulacao_media_v              matlab.ui.control.Spinner
        simulacao_media_label_v        matlab.ui.control.Label
        simular                        matlab.ui.control.Button
        panel_malha_fechada            matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        av44                           matlab.ui.control.Label
        av33                           matlab.ui.control.Label
        av22                           matlab.ui.control.Label
        av11                           matlab.ui.control.Label
        AutovaloresdeAPanel            matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        av4                            matlab.ui.control.Label
        av3                            matlab.ui.control.Label
        av2                            matlab.ui.control.Label
        av1                            matlab.ui.control.Label
        button_animacao                matlab.ui.control.Button
        grafico_velocidades            matlab.ui.control.UIAxes
        grafico_sinal_controle         matlab.ui.control.UIAxes
        grafico_erro_estimacao         matlab.ui.control.UIAxes
        grafico_angulo_haste           matlab.ui.control.UIAxes
        grafico_posicao_carrinho       matlab.ui.control.UIAxes
        grafico_covariancia            matlab.ui.control.UIAxes
        RudosTab                       matlab.ui.container.Tab
        GridLayout4                    matlab.ui.container.GridLayout
        RudonoSistemawPanel            matlab.ui.container.Panel
        checkbox_utilizar_simulacao_w  matlab.ui.control.CheckBox
        checkbox_multiplos_pdf_w       matlab.ui.control.CheckBox
        spinner_media_w                matlab.ui.control.Spinner
        ruido_label_media_w            matlab.ui.control.Label
        GerarRuidoButton               matlab.ui.control.Button
        desvio_w                       matlab.ui.control.Label
        variancia_w                    matlab.ui.control.Label
        valor_w                        matlab.ui.control.NumericEditField
        ValorEditFieldLabel_2          matlab.ui.control.Label
        drop_w                         matlab.ui.control.DropDown
        MedidadeDispersoDropDownLabel_2  matlab.ui.control.Label
        ruido_label_variancia_w        matlab.ui.control.Label
        ruido_label_desvio_padrao_w    matlab.ui.control.Label
        RudonosSensoresvPanel          matlab.ui.container.Panel
        checkbox_utilizar_simulacao_v  matlab.ui.control.CheckBox
        checkbox_multiplos_pdf_v       matlab.ui.control.CheckBox
        spinner_media_v                matlab.ui.control.Spinner
        ruido_label_media_v            matlab.ui.control.Label
        GerarRuidoButton_v             matlab.ui.control.Button
        desvio_v                       matlab.ui.control.Label
        variancia_v                    matlab.ui.control.Label
        valor_v                        matlab.ui.control.NumericEditField
        ValorEditFieldLabel            matlab.ui.control.Label
        drop_v                         matlab.ui.control.DropDown
        MedidadeDispersoDropDownLabel  matlab.ui.control.Label
        ruido_label_variancia_v        matlab.ui.control.Label
        ruido_label_desvio_padrao_v    matlab.ui.control.Label
        grafico_fdp_w                  matlab.ui.control.UIAxes
        grafico_ruido_w                matlab.ui.control.UIAxes
        grafico_fdp_v                  matlab.ui.control.UIAxes
        grafico_ruido_v                matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        % Parâmetros
        l = 0.641; % Comprimento da Haste
        m = 0.230; % Massa do Pêndulo
        M = 0.57; % Massa do Carrinho
        g = 9.81; % Aceleração da Gravidade
        Y = [];
        X = [];
        Y_nl = [];
        X_nl = [];
        Yhat = [];
        Xhat = []; 
        L_FK = [];
        L_EKF = [];
        
        multi_grafico = 0; % Mostra vários PDF's
        legendas_ruidos = {}; % Acumula sigma e sigma^2
        qtd_graficos = 0; % Description       
        ruidos_w = []; % armazena os dados dos ruídos
        ruidos_v = [];
        terminate_loop = 0;
    end
    
    methods (Access = public)
        
        function [f,h] = create_handles(app,x,u)
                      
            % Parâmetros
            l = 0.641; % Comprimento da Haste
            m = 0.230; % Massa do Pêndulo
            M = 0.57; % Massa do Carrinho
            g = 9.81; % Aceleração da Gravidade

            % Função que retorna o modelo não-linear na forma de equção de estados
            f = [x(2);
            (u+m*l*x(4)^2*sin(x(3))-m*g*sin(x(3))*cos(x(3)))/(M+m-m*cos(x(3))^2);
            x(4);
            (u*cos(x(3))-(M+m)*g*sin(x(3))+m*l*x(4)^2*sin(x(3))*cos(x(3)))/(m*l*cos(x(3))^2-(M+m)*l)];
            h = [x(1);x(3)]; 
            
            
            % %% Handles Não-Lineares
            f = matlabFunction(f); % Transforma f em uma função handle f_h
            h = matlabFunction(h); % Transforma h em uma função handle h_h
        end
        
        function [f,g,h] = create_transition_matrix(app,x,u,dt)
            %% Parâmetros
            l = 0.641; % Comprimento da Haste
            m = 0.230; % Massa do Pêndulo
            M = 0.57; % Massa do Carrinho
            g = 9.81; % Aceleração da Gravidade

            %% Função que retorna o modelo não-linear na forma de equção de estados
            f = [x(2);
            (u+m*l*x(4)^2*sin(x(3))-m*g*sin(x(3))*cos(x(3)))/(M+m-m*cos(x(3))^2);
            x(4);
            (u*cos(x(3))-(M+m)*g*sin(x(3))+m*l*x(4)^2*sin(x(3))*cos(x(3)))/(m*l*cos(x(3))^2-(M+m)*l)];
            h = [x(1);x(3)]; 
            
            %% Jacobianos para as Matrizes de transição
            f = simplify(jacobian(x' + f*dt,x));  % Matriz de transição de estados F (f_j)
%             g = simplify(jacobian(x' + f*dt,u));  % Matriz de transição de estados G (g_j)
            h = simplify(jacobian(h,x));          % Matriz de transição de estados H (h_j)
            
            f = matlabFunction(f); % Tranforma o jacobiano f_j em uma função handle F_j
%             g = matlabFunction(g); % Tranforma o jacobiano g_j em uma função handle G_j
            h = matlabFunction(h); % Tranforma o jacobiano h_j em uma função handle H_j
        
        end
        
        function habilita_controle(app)
            set(app.panel_matriz_q,'Enable','on');
            set(app.x,'Enable','on');
            set(app.dotx,'Enable','on');
            set(app.alpha,'Enable','on');
            set(app.dotalpha,'Enable','on');
            set(app.r,'Enable','on');
            set(app.panel_ganho_k,'Visible','on')
            set(app.panel_malha_fechada,'Visible','on')
        end
        
        function desabilita_controle(app)
            set(app.panel_matriz_q,'Enable','off');
            set(app.x,'Enable','off');
            set(app.dotx,'Enable','off');
            set(app.alpha,'Enable','off');
            set(app.dotalpha,'Enable','off');
            set(app.r,'Enable','off');
            set(app.panel_ganho_k,'Visible','off')
            set(app.panel_malha_fechada,'Visible','off')
        end
        

        function [Y,X,U,t] = linearized_simulation(app,A,B,C,x,K,mu_w,mu_v,sigma_w,sigma_v,dt,w,v,tempo_simulacao,X,Y,U,t)
            for i = 0:dt:tempo_simulacao
                u = -K*x;                          % Lei de controle com acurácia(erro sistemátíco) como ruído  
                x = x + (A*x + B*u + w)*dt;        % Estados do sistema
                y = C*x + v;              % Adicionando o ruído na medição
                X = [X x];                         % Acumulando o estado
                Y = [Y y];                         % Acumulando a saída
                U = [U u];                         % Sinal de Controle
                t = [t i];
                w =  mu_w + sigma_w * randn(4,1); % Criando o ruído no sistema
                v =  mu_v + sigma_v * randn(2,1); % Criando o ruído no no sensor
                % draw(x,2)
            end
        end
        
        function [Y,X,U,Y2,X2,U2,t] = non_linearized_simulation(app,f,h,x,x2,K,mu_w,mu_v,sigma_w,sigma_v,dt,w,v,tempo_simulacao,X,X2,Y,Y2,U,U2,t)
            for i = 0:dt:tempo_simulacao
                u = -K*x; 
                u2 = -K*x2;                                % Lei de controle com acurácia(erro sistemátíco) como ruído  
                x = x + (f(u,x(2),x(3),x(4)) + w)*dt;    % Estados do sistema
                x2 = x2 + (f(u2,x2(2),x2(3),x2(4)))*dt;      % Estados do sistema
                y = h(x(1),x(3)) + v;                    % Adicionando o ruído na medição
                y2 = h(x2(1),x2(3));                        % Adicionando o ruído na medição
                X = [X x];                               % Acumulando o estado
                X2 = [X2 x2];                               % Acumulando o estado
                Y = [Y y];                               % Acumulando a saída
                Y2 = [Y2 y2];                               % Acumulando a saída
                U = [U u];                               % Sinal de Controle
                U2 = [U2 u2];                               % Sinal de Controle
                t = [t i];                               % Acumulando o tempo
                w =  mu_w + sigma_w * randn(4,1);        % Criando o ruído no sistema
                v =  mu_v + sigma_v * randn(2,1);        % Criando o ruído no no sensor
                % draw(x,2)
            end
        end 
        function [Y,Y_nl,X,X_nl,U,U_nl,t] = non_linearized_and_linearized_simulation(app,A,B,C,f,h,x,x_nl,K,mu_w,mu_v,sigma_w,sigma_v,dt,w,v,w_nl,v_nl,tempo_simulacao,X,Y,U,t,X_nl,Y_nl,U_nl)
            for i = 0:dt:tempo_simulacao
                u = -K*x;                                                   % Lei de controle com acurácia(erro sistemátíco) como ruído
                u_nl = -K*x_nl;                                             % Lei de controle com acurácia(erro sistemátíco) como ruído  
                x = x + (A*x + B*u + w)*dt;   
                x_nl = x_nl + (f(u_nl,x_nl(2),x_nl(3),x_nl(4)) + w_nl)*dt;  % Estados do sistema
                y = C*x + v;                                                % Adicionando o ruído na medição
                y_nl = h(x_nl(1),x_nl(3)) + v_nl;                           % Adicionando o ruído na medição
                X = [X x];                                                  % Acumulando o estado
                X_nl = [X_nl x_nl];                                         % Acumulando o estado
                Y = [Y y];                                                  % Acumulando a saída
                Y_nl = [Y_nl y_nl];                                         % Acumulando a saída                    
                U = [U u]; 
                U_nl = [U_nl u_nl];                                         % Sinal de Controle
                t = [t i];                                                  % Acumulando o tempo
                w =  mu_w + sigma_w * randn(4,1);                           % Criando o ruído no sistema
                v =  mu_v + sigma_v * randn(2,1);                           % Criando o ruído no no sensor
                w_nl =  mu_w + sigma_w * randn(4,1);                        % Criando o ruído no sistema
                v_nl =  mu_v + sigma_v * randn(2,1);                        % Criando o ruído no no sensor
                % draw(x,2)
            end 
        end
        
        function [Y,Yhat,X,Xhat,Px,U,Yt,t,L_FK] = kalman(app,A,B,C,x,xhat,K,Q_w,Q_v,mu_w,mu_v,sigma_w,sigma_v,P,dt,w,v,tempo_simulacao,X,Y,Xhat,Yhat,U,t,Px,Yt,L_FK)
            %% sistema(FK,sistema_linearizado,lqr,w,v)
            Q = Q_w;                               % Matriz de covariância do sistema
            R = Q_v;                               % Matriz de covariância do sensor
            for i = 0:dt:tempo_simulacao
              u = -K*xhat;                         % Lei de controle      
              x = x+(A*x + B*u + w)*dt;            % Estado diferencial do sistema
              y = C*x + v;                         % Saída não corrompida
              % Gerando novo ruído
              w =  mu_w + sigma_w * randn(4,1);    % Criando o ruído no sistema
              v =  mu_v + sigma_v * randn(2,1);    % Criando o ruído no no sensor
             
              F = eye(4,4)+dt*A;                   % Matriz de Transição de Estados
              
              % Predição
              xhat  = F*xhat+ dt*B*u;              % Predição do estado estimado 
              P  = F*P*F'+ Q;                      % Predição da covariância
              
              % Resíduos
              S = C*P*C'+R;                        % Resíduo da covariância
              yt = y - C*xhat;                     % Resíduo da medição
              
              % Atualização
              L  = P*C'*inv(S);                    % Equação do Ganho de Kalman 
              xhat = xhat + L*(yt);                % Equação da atualização do estado estimado
              yhat = [xhat(1);xhat(3)];            % Medição da estimativa
              P = P - L*C*P;                       % Equação de atualização da covariância
              
              % Acumuladores
              X = [X x];                           % Acumulando o estado
              Y = [Y y];                           % Acumulando a saída não corrompida
              Xhat = [Xhat xhat];                  % Acumulando a estimativa
              Yhat = [Yhat yhat];                  % Acumulando o Ganho de Kalman
              U = [U u];                           % Acumulando o sinal de Controle
              Yt = [Yt yt];                        % Acumulando o erro de estimativa
              Px = [Px diag(P)];                   % Acumulando o a covariância do erro de estimativa  
              t = [t i];                           % Acumulando o tempo diferencial
              
              if isempty(L_FK)
                  L_FK = L
              else
                  L_FK = [L_FK L]
              end
              %     draw(xhat,2)             
            end
        end
        
        function [Y,Yhat,X,Xhat,Px,U,Yt,t,L_EKF] = extended_kalman(app,C,f,h,F_j,x,xhat,K,Q_w,Q_v,mu_w,mu_v,sigma_w,sigma_v,P,dt,w,v,tempo_simulacao,X,Y,Xhat,Yhat,U,t,Px,Yt,L_EKF)
            Q = Q_w;                               % Matriz de covariância do sistema
            R = Q_v;                               % Matriz de covariância do sensor
            for i = 0:dt:tempo_simulacao                       
                % Lei de controle
                u = -K*xhat;                               % Sinal de controle
                 
                % Simulação do sistema real ruidoso
                x =  x + (f(u,x(2),x(3),x(4)) + w)*dt;   % Estados dos sistema não-linear
                y = h(x(1),x(3)) + v;                       % Medição da saída - sensor
                
                % Predição
                xhat = xhat + f(u,xhat(2),xhat(3),xhat(4))*dt;                 % Estado Predito
                yhat = h(xhat(1),xhat(3));                                        % Medição do estado predito
                P  = F_j(dt,u,xhat(3),xhat(4))*P*F_j(dt,u,xhat(3),xhat(4))'+ Q;  % Covariância predita
               
                % Resíduos (erros)
                S = C*P*C'+R;             % Resíduo da covariância 
                yt = y - yhat;            % Resíduo estimativa
                
                % Atualização
                L = P*C'*inv(S);          % Cálculo do ganho de Kalman
                xhat = xhat + L*(yt);     % Atualização do estado predito
                yhat = [xhat(1);xhat(3)]; % Medição do estado predito
                P = P - L*C*P;            % Atualização da covariância
                
                % Acumuladores
                X = [X x];                % Acumulando os estados reais
                Y = [Y y];                % Acumulando as saídas dos sensores
                Xhat = [Xhat xhat];       % Acumulando os estados estimados
                Yhat = [Yhat yhat];       % Acumulando as saídas estimadas
                U = [U u];                % Acumulando o sinal de Controle
                Yt = [Yt yt];             % Acumulando o erro de estimativa
                Px = [Px diag(P)];        % Acumulando o a covariância do erro de estimativa  
                t = [t i];                % Acumulando o tempo
                
                if isempty(L_EKF)
                   L_EKF = L
                else
                   L_EKF = [L_EKF L]
                end
            
                % Gerando novo ruído
                w =  mu_w + sigma_w * randn(4,1); % Criando o ruído no sistema
                v =  mu_v + sigma_v * randn(2,1); % Criando o ruído no no sensor
            end  
        end
        
        function grafico = configura_grafico(app,grafico,title,xlabel,ylabel,xgrid,ygrid,box,position)             
            grafico.Title.String = title;
            grafico.XLabel.String = xlabel;
            grafico.YLabel.String = ylabel;
            grafico.XGrid = xgrid;
            grafico.YGrid = ygrid;
%           grafico.Position = position;
            grafico.Box = box;
        end  
        
         function legenda(app,~,evnt,grafico)
            % This function uses the postion data from where the legend was
            % clicked to toggle a line on/off
            
            % First, get all of the lines
            lines = grafico.Children;
            
            % Next, create segments based off of the number of lines. Based on
            % where the user clicked in the legend, we will assign that click
            % location to a line. This call generates a grid of equally spaced
            % ranges, with the number of ranges equaling the number of lines
            click_locations = linspace(0,1,numel(lines)+1);
            
            % Get corresponding bin based on the 2nd value of
            % evnt.IntersectionPoint. This corresponds to the vertical distance
            % within the legend, which we can use to identify what line
            % (roughly) in the legend was clicked
            line_num = discretize(evnt.IntersectionPoint(2),click_locations);
            
            % Set the visiblility of the corresponding line 'off' or 'on'
            relevant_line = lines(line_num);
            if strcmp(relevant_line.Visible,'on')
                relevant_line.Visible = 'off';
            else
                relevant_line.Visible = 'on';
            end
        end

     
    end
    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% https://www.toptal.com/designers/htmlarrows/math/ 
            app.simulacao_variancia_label_w.Text = strcat(char(963),char(178))
            app.simulacao_variancia_label_v.Text = strcat(char(963),char(178))
            app.simulacao_media_label_w.Text = char(956)
            app.simulacao_media_label_v.Text = char(956)
            
            app.ruido_label_media_w.Text = strcat('Média',char(160),char(40),char(956),char(41));
            app.ruido_label_media_v.Text = strcat('Média',char(160),char(40),char(956),char(41));
            app.ruido_label_variancia_w.Text = strcat('Variância',char(160),char(40),char(963),char(178),char(41))
            app.ruido_label_variancia_v.Text = strcat('Variância',char(160),char(40),char(963),char(178),char(41))
            app.ruido_label_desvio_padrao_w.Text = strcat('Desvio Padrão',char(160),char(40),char(963),char(41))
            app.ruido_label_desvio_padrao_v.Text = strcat('Desvio Padrão',char(160),char(40),char(963),char(41))
               
            %% Panel estimador
            set(app.panel_estimador,'Enable','off')
            set(app.ci_estimador_x,'Enable','off')                
            set(app.ci_estimador_dotx,'Enable','off')
            set(app.ci_estimador_alpha,'Enable','off')
            set(app.ci_estimador_dotalpha,'Enable','off')
            set(app.ci_estimador_x_label,'Enable','off')                
            set(app.ci_estimador_dotx_label,'Enable','off')
            set(app.ci_estimador_alpha_label,'Enable','off')
            set(app.ci_estimador_dotalpha_label,'Enable','off')    
        end

        % Value changed function: dropbox_filtragem
        function dropbox_filtragemValueChanged(app, event)
            app.dropbox_filtragem.ItemsData = [1 2]
            value = app.dropbox_filtragem.Value;
                                  
            if value == 1
                
                app.panel_linearizacao.Enable = 'on';
                set(app.checkbox_linearizado,'Enable','on');
                set(app.checkbox_nao_linear,'Enable','on');
                app.panel_filtragem.Enable = 'off'
                set(app.radio_filtro_kalman,'Enable','off');
                set(app.radio_filtro_kalman_estendido,'Enable','off');
                app.checkbox_linearizado.Value = 1;
                app.checkbox_nao_linear.Value = 0;
                set(app.simular,'Enable','on')
                
                %% Panel estimador
                set(app.panel_estimador,'Enable','off')
                set(app.ci_estimador_x,'Enable','off')                
                set(app.ci_estimador_dotx,'Enable','off')
                set(app.ci_estimador_alpha,'Enable','off')
                set(app.ci_estimador_dotalpha,'Enable','off')
                set(app.ci_estimador_x_label,'Enable','off')                
                set(app.ci_estimador_dotx_label,'Enable','off')
                set(app.ci_estimador_alpha_label,'Enable','off')
                set(app.ci_estimador_dotalpha_label,'Enable','off')
                
                legend(app.grafico_posicao_carrinho,'hide')
                legend(app.grafico_angulo_haste,'hide') 
                legend(app.grafico_velocidades,'hide')
                legend(app.grafico_sinal_controle,'hide') 
                legend(app.grafico_covariancia,'hide')
                legend(app.grafico_erro_estimacao,'hide')
                
                cla(app.grafico_posicao_carrinho);
                cla(app.grafico_angulo_haste);
                cla(app.grafico_velocidades);
                cla(app.grafico_sinal_controle);
                cla(app.grafico_covariancia);
                cla(app.grafico_erro_estimacao);                 


                set(app.grafico_covariancia,'Visible','off')
                set(app.grafico_erro_estimacao,'Visible','off')
                
                
            elseif value == 2
                
                set(app.incluir_ruido_sistema,'Enable','on')
                set(app.incluir_ruido_sensor,'Enable','on')
                set(app.simular,'Enable','on')
                app.panel_linearizacao.Enable = 'off';
                set(app.checkbox_linearizado,'Enable','off');
                set(app.checkbox_nao_linear,'Enable','off');
                app.panel_filtragem.Enable = 'on';
                set(app.radio_filtro_kalman,'Enable','on');
                set(app.radio_filtro_kalman_estendido,'Enable','on');
                set(app.simular,'Enable','on')
                
                %% Panel estimador
                set(app.panel_estimador,'Enable','on')
                set(app.ci_estimador_x,'Enable','on')                
                set(app.ci_estimador_dotx,'Enable','on')
                set(app.ci_estimador_alpha,'Enable','on')
                set(app.ci_estimador_dotalpha,'Enable','on')
                set(app.ci_estimador_x_label,'Enable','on')                
                set(app.ci_estimador_dotx_label,'Enable','on')
                set(app.ci_estimador_alpha_label,'Enable','on')
                set(app.ci_estimador_dotalpha_label,'Enable','on')                
                
                 legend(app.grafico_posicao_carrinho,'hide')
                legend(app.grafico_angulo_haste,'hide') 
                legend(app.grafico_velocidades,'hide')
                legend(app.grafico_sinal_controle,'hide') 
                legend(app.grafico_covariancia,'hide')
                legend(app.grafico_erro_estimacao,'hide')
                
                cla(app.grafico_posicao_carrinho);
                cla(app.grafico_angulo_haste);
                cla(app.grafico_velocidades);
                cla(app.grafico_sinal_controle);
                cla(app.grafico_covariancia);
                cla(app.grafico_erro_estimacao);    
                
                app.grafico_covariancia.Visible = 'on';
                app.grafico_erro_estimacao.Visible = 'on';
               
            end
        end

        % Value changed function: checkbox_incluir_controle
        function checkbox_incluir_controleValueChanged(app, event)
            value = app.checkbox_incluir_controle.Value;
            if value == 1
                %% Habilita o LQR
                habilita_controle(app)
            else
                %% Desabilita o LQR
                desabilita_controle(app)
            end
        end

        % Button pushed function: simular
        function simularButtonPushed(app, event)
            syms x1 x2 x3 x4 u dt % l M m g J t dt
            x = [x1 x2 x3 x4];      % Vetor de Estados
            [f,h] = create_handles(app,x,u)
            [F_j,G_j,H_j] = create_transition_matrix(app,x,u,dt)
            
            %% Filtragem
            app.dropbox_filtragem.ItemsData = [1 2]
            filtrar = app.dropbox_filtragem.Value
                  
            %% Filtros
            KF = app.radio_filtro_kalman.Value;
            EKF = app.radio_filtro_kalman_estendido.Value;
            
            %% Período de amostragem
            dt = app.periodo_amostragem.Value;
            
            %% Tempo de Simulação
            tempo_simulacao = app.tempo_simulacao.Value
            
            %% Inclusão do controle LQR
            incluir_controle_lqr = app.checkbox_incluir_controle.Value;
            
            %% Linearização
            linearizado = app.checkbox_linearizado.Value;
            nao_linear = app.checkbox_nao_linear.Value;
            
                 
            %% Inclusão do ruído no sistema
            incluir_ruido_sistema = app.incluir_ruido_sistema.Value;
            
            %% Inclusão do ruído no sensor
            incluir_ruido_sensor = app.incluir_ruido_sensor.Value;
               
            % Condições Iniciais dos estados reais
            ci_x = app.ci_x.Value;
            ci_dotx = app.ci_dotx.Value;
            ci_alpha = (pi/180)*app.ci_alpha.Value;
            ci_dotalpha = app.ci_dotalpha.Value;
            ci = [ ci_x ci_dotx ci_alpha ci_dotalpha]
            
            % Condições Iniciais dos estados estimados
            ci_estimador_x = app.ci_estimador_x.Value;
            ci_estimador_dotx = app.ci_estimador_dotx.Value;
            ci_estimador_alpha = (pi/180)*app.ci_estimador_alpha.Value;
            ci_estimador_dotalpha = app.ci_estimador_dotalpha.Value;
            ci_estimador = [ ci_estimador_x ci_estimador_dotx ci_estimador_alpha ci_estimador_dotalpha]
            
            %% Coletando os dados dos ruídos
            mu_w = app.simulacao_media_w.Value;
            mu_v = app.simulacao_media_v.Value;
%             app.drop_w.ItemsData = [1 2]
%             app.drop_v.ItemsData = [1 2]
%             drop_w = app.drop_w.Value
%             drop_v = app.drop_v.Value
            valor_w = app.simulacao_variancia_w.Value;
            valor_v = app.simulacao_variancia_v.Value;
            
            %% Selecionando o valor correto dos drops
%             if drop_w == 1
%                 variancia_w = valor_w;
%                 desvio_padrao_w = sqrt(variancia_w);
%             elseif drop_w == 2
%                 desvio_padrao_w = valor_w;
%                 variancia_w = desvio_padrao_w^2;
%             end
%             
%             if drop_v == 1
%                 variancia_v = valor_v;
%                 desvio_padrao_v = sqrt(variancia_v);
%             elseif drop_v == 2
%                 desvio_padrao_v = valor_v;
%                 variancia_v = desvio_padrao_v^2;
%             end
            
            %% Desvio Padrão dos ruídos
%             sigma_w = desvio_padrao_w;  
%             sigma_v = desvio_padrao_v; 
              sigma_w = sqrt(valor_w)  
              sigma_v = sqrt(valor_v); 
            
            
            %% Matrizes das Equações de Estados Linearizadas      
            A = [ 0 1 0 0; 0 0 -(app.g*app.m)/app.M 0; 0 0 0 1; 0 0 -(app.g*(app.M + app.m))/(-app.l*app.M) 0];
            B = [0; 1/app.M; 0; -(1/(app.l*app.M))];
            C = [1 0 0 0; 0 0 1 0];
            D = [0;0];
            
            %% Recebendo os valores de Q e R
            q_x = app.x.Value;
            q_dotx = app.dotx.Value;
            q_alpha = app.alpha.Value;
            q_dotalpha = app.dotalpha.Value;
            R = app.r.Value
            
            %% Matriz Q e R
            Q = [q_x 0 0 0;0 q_dotx 0 0;0 0 q_alpha 0; 0 0 0 q_dotalpha]
            K = lqr( A, B, Q, R)  % Matriz de Ganho de realimentação
            if app.checkbox_incluir_controle.Value == 0
                K = [0 0 0 0]
            end
            
            %% Parâmetros probabilísticos
            % Matriz de covariância
            px = eye(4,4);                     % Criando a matriz de covariância entre o sistema e as estimativas          
            P = eye(4,4);                      % Criando a matriz de covariância entre o sistema e as estimativas               
            Px = diag(px);                     % Criando o vetor acumulador de diagonal principal das matrize covariância
            
            %Matrizes de Incertezas
            Q_w = dt*sigma_w*eye(4,4);         % Matriz de incertezas do processo
            Q_v = sigma_v^2*eye(2,2);          % Matriz de incertezas da medição
            
            % Ruídos iniciais
            w =  mu_w + sigma_w * randn(4,1); % Criando o ruído no sistema
            v =  mu_v + sigma_v * randn(2,1); % Criando o ruído no no sensor
            w_nl =  mu_w + sigma_w * randn(4,1); % Criando o ruído no sistema
            v_nl =  mu_v + sigma_v * randn(2,1); % Criando o ruído no no sensor
            
            
            %% Inicialização das variáveis
            x = ci';                    % Estado real inicial
            X = x;                      % Inicialiando o acumular de estados
            y = [x(1);x(3)]             % Saída inicial
            Y = y;                      % Inicialiando o acumular de saídas
            x2 = ci';                   % Estado real inicial
            X2 = x2;                    % Inicialiando o acumular de estados
            y2 = [x2(1);x2(3)]          % Saída inicial
            Y2 = y2;                    % Inicialiando o acumular de saídas
            x_nl = ci';                 % Estado real inicial
            X_nl = x_nl;                % Inicialiando o acumular de estados
            y_nl = [x_nl(1);x_nl(3)]    % Saída inicial
            Y_nl = y_nl;                % Inicialiando o acumular de saídas
            xhat = ci_estimador';       % Estados estimado inicial
            Xhat = xhat;                % Inicialiando o acumular de estados estimados
            yhat = [xhat(1);xhat(3)];   % Saída estimada inicial
            Yhat = yhat;                % Inicialiando o acumular de saídas estimadas
            u = -K*x;                   % Lei de controle inicial
            U = u;                      % Inicializando o acumulador da lei de controle
            u2 = -K*x2;                 % Lei de controle inicial
            U2 = u2;                    % Inicializando o acumulador da lei de controle
            u_nl = -K*x_nl;             % Lei de controle inicial
            U_nl = u_nl;                % Inicializando o acumulador da lei de controle
            yt = y - yhat;              % Erro de estimativa inicial
            Yt = yt;                    % Inicializando o acumulador da lei de controle dos erros de estimativa
            t = 0                       % Inicializando o contador temporal
            L_FK = app.L_FK
            L_EKF = app.L_EKF
            
            cla(app.grafico_posicao_carrinho);
            cla(app.grafico_angulo_haste);
            cla(app.grafico_sinal_controle);
            cla(app.grafico_velocidades);             
            cla(app.grafico_erro_estimacao);
            cla(app.grafico_covariancia);
            
            if filtrar == 1
                if linearizado == 1 && nao_linear == 0
                     if incluir_controle_lqr == 1
                         if incluir_ruido_sistema == 1 && incluir_ruido_sensor == 1    
                             %% sistema(linear,lqr,ruido_w,ruido_v)  
                             [Y,X,U,t] = linearized_simulation(app,A,B,C,x,K,mu_w,mu_v,sigma_w,sigma_v,dt,w,v,tempo_simulacao,X,Y,U,t)

                         elseif incluir_ruido_sistema == 1 && incluir_ruido_sensor == 0
                             %% sistema(linear,lqr,ruido_w)
                             [Y,X,U,t] = linearized_simulation(app,A,B,C,x,K,mu_w,0,sigma_w,0,dt,w,0,tempo_simulacao,X,Y,U,t)
                                
                          elseif incluir_ruido_sistema == 0 && incluir_ruido_sensor == 1
                             %% sistema(linear,lqr,ruido_v)
                             [Y,X,U,t] = linearized_simulation(app,A,B,C,x,K,0,mu_v,0,sigma_v,dt,0,v,tempo_simulacao,X,Y,U,t)
                                                               
                          elseif incluir_ruido_sistema == 0 && incluir_ruido_sensor == 0
                             %% sistema(linear,lqr)
                             [Y,X,U,t] = linearized_simulation(app,A,B,C,x,K,0,0,0,0,dt,0,0,tempo_simulacao,X,Y,U,t)                         
                         end
                     else
                         %% sistema(linear)
                         if incluir_ruido_sistema == 1 && incluir_ruido_sensor == 1    
                             %% sistema(linear,ruido_w,ruido_v)  
                             [Y,X,U,t] = linearized_simulation(app,A,B,C,x,K,mu_w,mu_v,sigma_w,sigma_v,dt,w,v,tempo_simulacao,X,Y,U,t)

                         elseif incluir_ruido_sistema == 1 && incluir_ruido_sensor == 0
                             %% sistema(linear,ruido_w)
                             [Y,X,U,t] = linearized_simulation(app,A,B,C,x,K,mu_w,0,sigma_w,0,dt,w,0,tempo_simulacao,X,Y,U,t)
                                
                          elseif incluir_ruido_sistema == 0 && incluir_ruido_sensor == 1
                             %% sistema(linear,ruido_v)
                             [Y,X,U,t] = linearized_simulation(app,A,B,C,x,K,0,mu_v,0,sigma_v,dt,0,v,tempo_simulacao,X,Y,U,t)
                                                               
                          elseif incluir_ruido_sistema == 0 && incluir_ruido_sensor == 0
                             %% sistema(linear)
                             [Y,X,U,t] = linearized_simulation(app,A,B,C,x,K,0,0,0,0,dt,0,0,tempo_simulacao,X,Y,U,t)                         
                         end                                           
                     end 
                app.Y = Y;  
                app.X = X;
                
                set(app.grafico_covariancia,'Visible','off')
                set(app.grafico_erro_estimacao,'Visible','off')
                legend(app.grafico_posicao_carrinho,'hide')
                legend(app.grafico_angulo_haste,'hide')
                legend(app.grafico_sinal_controle,'hide')
                legend(app.grafico_erro_estimacao,'hide')
                legend(app.grafico_covariancia,'hide')
                
                plot(app.grafico_posicao_carrinho,t,Y(1,:));
                plot(app.grafico_angulo_haste,t,(180/pi)*Y(2,:))
                plot(app.grafico_velocidades,t,X(2,:),t,X(4,:));
                legend(app.grafico_velocidades, 'Velocidade Carrinho - m/s','Velocidade Pêndulo - rad/s')
                plot(app.grafico_sinal_controle,t,U);

                if app.mostrar_janela.Value == 1        
                
                fig = uifigure;
                tl= tiledlayout(fig,2,2,'TileSpacing',"compact",'Padding',"compact");
                title(tl,'Simulação do Sistema Linearizado')
                
                grafico_posicao_carrinho = nexttile(tl)             
                plot(grafico_posicao_carrinho,t,Y(1,:));
                title(grafico_posicao_carrinho,'Gráfico da Posição do Carrinho vs. Tempo')
                ylabel(grafico_posicao_carrinho,'Posição do Carrinho - m')
                xlabel(grafico_posicao_carrinho,'Tempo - s')
                grid(grafico_posicao_carrinho,'on');
                
                grafico_angulo_haste = nexttile(tl)
                plot(grafico_angulo_haste,t,(180/pi)*Y(2,:))
                title(grafico_angulo_haste,'Gráfico do Ângulo da Haste vs. Tempo')
                ylabel(grafico_angulo_haste,'Ângulo da Haste - º')
                xlabel(grafico_angulo_haste,'Tempo - s')
                grid(grafico_angulo_haste,'on');
                
                grafico_velocidades = nexttile(tl)                
                plot(grafico_velocidades,t,X(2,:),t,X(4,:));
                legend(grafico_velocidades, 'Velocidade Carrinho - m/s','Velocidade Pêndulo - rad/s')
                title(grafico_velocidades,'Gráfico de Velocidades linear e angular vs. Tempo')
                ylabel(grafico_velocidades,'velocidades')
                xlabel(grafico_velocidades,'Tempo - s')
                grid(grafico_velocidades,'on');
                l3 = legend(grafico_velocidades); 
                l3.ButtonDownFcn = {@app.legenda,app.grafico_velocidades};
                
                grafico_sinal_controle = nexttile(tl)
                plot(grafico_sinal_controle,t,U);
                title(grafico_sinal_controle,'Gráfico do Sinal de Controle vs. Tempo')
                ylabel(grafico_sinal_controle,'Sinal de Controle')
                xlabel(grafico_sinal_controle,'Tempo - s')
                grid(grafico_sinal_controle,'on');
                end
                
                elseif linearizado == 0 && nao_linear == 1
                    if incluir_controle_lqr == 1
                         if incluir_ruido_sistema == 1 && incluir_ruido_sensor == 1    
                             %% sistema(nao_linear,lqr,ruido_w,ruido_v)
                             [Y,X,U,Y2,X2,U2,t] = non_linearized_simulation(app,f,h,x,x2,K,mu_w,mu_v,sigma_w,sigma_v,dt,w,v,tempo_simulacao,X,X2,Y,Y2,U,U2,t)                                 
                         elseif incluir_ruido_sistema == 1 && incluir_ruido_sensor == 0
                             %% sistema(nao_linear,lqr,ruido_w)
                             [Y,X,U,Y2,X2,U2,t] = non_linearized_simulation(app,f,h,x,x2,K,mu_w,0,sigma_w,0,dt,w,0,tempo_simulacao,X,X2,Y,Y2,U,U2,t)                                 
                         elseif incluir_ruido_sistema == 0 && incluir_ruido_sensor == 1
                             %% sistema(nao_linear,lqr,ruido_w)
                             [Y,X,U,Y2,X2,U2,t] = non_linearized_simulation(app,f,h,x,x2,K,0,mu_v,0,sigma_v,dt,0,v,tempo_simulacao,X,X2,Y,Y2,U,U2,t)                        
                         elseif incluir_ruido_sistema == 0 && incluir_ruido_sensor == 0
                             %% sistema(nao_linear,lqr)
                             [Y,X,U,Y2,X2,U2,t] = non_linearized_simulation(app,f,h,x,x2,K,0,0,0,0,dt,0,0,tempo_simulacao,X,X2,Y,Y2,U,U2,t)                        
                         end
                            
                         
                    else
                         %% sistema(nao_linear)
                         if incluir_ruido_sistema == 1 && incluir_ruido_sensor == 1    
                             %% sistema(nao_linear,ruido_w,ruido_v)
                             [Y,X,U,Y2,X2,U2,t] = non_linearized_simulation(app,f,h,x,x2,K,mu_w,mu_v,sigma_w,sigma_v,dt,w,v,tempo_simulacao,X,X2,Y,Y2,U,U2,t)
    
                         elseif incluir_ruido_sistema == 1 && incluir_ruido_sensor == 0
                             %% sistema(nao_linear,ruido_w)
                             [Y,X,U,Y2,X2,U2,t] = non_linearized_simulation(app,f,h,x,x2,K,mu_w,0,sigma_w,0,dt,w,0,tempo_simulacao,X,X2,Y,Y2,U,U2,t)
                         
                         elseif incluir_ruido_sistema == 0 && incluir_ruido_sensor == 1
                             %% sistema(nao_linear,ruido_w)
                             [Y,X,U,Y2,X2,U2,t] = non_linearized_simulation(app,f,h,x,x2,K,0,mu_v,0,sigma_v,dt,0,v,tempo_simulacao,X,X2,Y,Y2,U,U2,t)

                         elseif incluir_ruido_sistema == 0 && incluir_ruido_sensor == 0
                             %% sistema(nao_linear)
                             [Y,X,U,Y2,X2,U2,t] = non_linearized_simulation(app,f,h,x,x2,K,0,0,0,0,dt,0,0,tempo_simulacao,X,X2,Y,Y2,U,U2,t)
                         
                         end
                                                     
                    end
                    app.Y = Y;  
                    app.X = X;
                    
                    set(app.grafico_covariancia,'Visible','off')
                    set(app.grafico_erro_estimacao,'Visible','off')
                    legend(app.grafico_posicao_carrinho,'hide')
                    legend(app.grafico_angulo_haste,'hide')
                    legend(app.grafico_sinal_controle,'hide')
                    legend(app.grafico_erro_estimacao,'hide')
                    legend(app.grafico_covariancia,'hide')  
                    
                    plot(app.grafico_posicao_carrinho,t,Y(1,:));
                    hold(app.grafico_posicao_carrinho,'on');
                    plot(app.grafico_posicao_carrinho,t,Y2(1,:));
                    legend(app.grafico_posicao_carrinho, 'Sistema Real','Média (Esperança)')
                    hold(app.grafico_posicao_carrinho,'off');
                    
                    plot(app.grafico_angulo_haste,t,(180/pi)*Y(2,:))
                    hold(app.grafico_angulo_haste,'on');
                    plot(app.grafico_angulo_haste,t,(180/pi)*Y2(2,:));
                    legend(app.grafico_angulo_haste, 'Sistema Real','Média (Esperança)')
                    hold(app.grafico_angulo_haste,'off');
                    
                    plot(app.grafico_velocidades,t,X(2,:),t,X(4,:));
                    hold(app.grafico_velocidades,'on');
                    plot(app.grafico_velocidades,t,X2(2,:),t,X2(4,:));
                    legend(app.grafico_velocidades, 'Velocidade Carrinho - Sistema Real ','Velocidade Haste - Sistema Real ','Velocidade Carrinho - Média (Esperança)','Velocidade Haste - Média (Esperança)')
                    hold(app.grafico_velocidades,'off');
                    
                    plot(app.grafico_sinal_controle,t,U);
                    hold(app.grafico_velocidades,'on');
                    plot(app.grafico_sinal_controle,t,U2);
                    hold(app.grafico_velocidades,'off');

                    
                    if app.mostrar_janela.Value == 1                        
                    
                    fig = uifigure;
                    tl= tiledlayout(fig,2,2,'TileSpacing',"compact",'Padding',"compact");
                    title(tl,'Simulação do Sistema Não-Linear')
                    
    
                    grafico_posicao_carrinho = nexttile(tl)  
                    plot(grafico_posicao_carrinho,t,Y(1,:)); 
                    hold(grafico_posicao_carrinho,'on');
                    plot(grafico_posicao_carrinho,t,Y2(1,:));
                    legend(grafico_posicao_carrinho, 'Sistema Real','Média (Esperança)')
                    hold(grafico_posicao_carrinho,'off');
                    title(grafico_posicao_carrinho,'Gráfico da Posição do Carrinho vs. Tempo')
                    ylabel(grafico_posicao_carrinho,'Posição do Carrinho - m')
                    xlabel(grafico_posicao_carrinho,'Tempo - s')
                    grid(grafico_posicao_carrinho,'on');
                    l1 = legend(grafico_posicao_carrinho); 
                    l1.ButtonDownFcn = {@app.legenda,grafico_posicao_carrinho};
                     
                    grafico_angulo_haste = nexttile(tl)  
                    plot(grafico_angulo_haste,t,(180/pi)*Y(2,:))
                    hold(grafico_angulo_haste,'on');
                    plot(grafico_angulo_haste,t,(180/pi)*Y2(2,:));
                    legend(grafico_angulo_haste, 'Sistema Real','Média (Esperança)')
                    hold(grafico_angulo_haste,'off');
                    title(grafico_angulo_haste,'Gráfico do Ângulo da Haste vs. Tempo')
                    ylabel(grafico_angulo_haste,'Ângulo da Haste - º')
                    xlabel(grafico_angulo_haste,'Tempo - s')
                    grid(grafico_angulo_haste,'on');
                    l2 = legend(grafico_angulo_haste); 
                    l2.ButtonDownFcn = {@app.legenda,grafico_angulo_haste};
                    
                    grafico_velocidades = nexttile(tl)
                    plot(grafico_velocidades,t,X(2,:),t,X(4,:));
                    hold(grafico_velocidades,'on');
                    plot(grafico_velocidades,t,X2(2,:),t,X2(4,:));
                    legend(grafico_velocidades, 'Velocidade Carrinho - Sistema Real ','Velocidade Haste - Sistema Real ','Velocidade Carrinho - Média (Esperança)','Velocidade Haste - Média (Esperança)')
                    hold(grafico_velocidades,'off');
                    title(grafico_velocidades,'Gráfico de Velocidades linear e angular vs. Tempo')
                    ylabel(grafico_velocidades,'velocidades')
                    xlabel(grafico_velocidades,'Tempo - s')
                    grid(grafico_velocidades,'on');
                    l3 = legend(grafico_velocidades); 
                    l3.ButtonDownFcn = {@app.legenda,grafico_velocidades};
                    
                    grafico_sinal_controle = nexttile(tl)
                    plot(grafico_sinal_controle,t,U);
                    hold(grafico_velocidades,'on');
                    plot(grafico_sinal_controle,t,U2);
                    hold(grafico_velocidades,'off');
                    title(grafico_sinal_controle,'Gráfico do Sinal de Controle vs. Tempo')
                    ylabel(grafico_sinal_controle,'Sinal de Costrole')
                    xlabel(grafico_sinal_controle,'Tempo - s')
                    grid(grafico_sinal_controle,'on');
                    l4 = legend(grafico_sinal_controle); 
                    l4.ButtonDownFcn = {@app.legenda,grafico_sinal_controle};
                    end

                
                elseif  linearizado == 1 && nao_linear == 1
                     if incluir_controle_lqr == 1
                          if incluir_ruido_sistema == 1 && incluir_ruido_sensor == 1    
                             %% sistema(nao_linear,lqr,ruido_w,ruido_v)
                             [Y,Y_nl,X,X_nl,U,U_nl,t] = non_linearized_and_linearized_simulation(app,A,B,C,f,h,x,x_nl,K,mu_w,mu_v,sigma_w,sigma_v,dt,w,v,w_nl,v_nl,tempo_simulacao,X,Y,U,t,X_nl,Y_nl,U_nl)
%                            [Y,Y_nl,X,X_nl,U,U_nl,t] = non_linearized_and_linearized_simulation(app,A,B,C,f,h,x,x_nl,K,mu_w,mu_v,sigma_w,sigma_v,dt,w,v,w_nl,v_nl,tempo_simulacao,X,Y,U,t,X_nl,Y_nl,U_nl)
%    
                         elseif incluir_ruido_sistema == 1 && incluir_ruido_sensor == 0
                             %% sistema(nao_linear,lqr,ruido_w)
                             [Y,Y_nl,X,X_nl,U,U_nl,t] = non_linearized_and_linearized_simulation(app,A,B,C,f,h,x,x_nl,K,mu_w,0,sigma_w,0,dt,w,0,w_nl,0,tempo_simulacao,X,Y,U,t,X_nl,Y_nl,U_nl)
%                            [Y,Y_nl,X,X_nl,U,U_nl,t] = non_linearized_and_linearized_simulation(app,A,B,C,f,h,x,x_nl,K,mu_w,mu_v,sigma_w,sigma_v,dt,w,v,w_nl,v_nl,tempo_simulacao,X,Y,U,t,X_nl,Y_nl,U_nl)
%         
                         elseif incluir_ruido_sistema == 0 && incluir_ruido_sensor == 1
                             %% sistema(nao_linear,lqr,ruido_w)
                             [Y,Y_nl,X,X_nl,U,U_nl,t] = non_linearized_and_linearized_simulation(app,A,B,C,f,h,x,x_nl,K,0,mu_v,0,sigma_v,dt,0,v,0,v_nl,tempo_simulacao,X,Y,U,t,X_nl,Y_nl,U_nl)
%                            [Y,Y_nl,X,X_nl,U,U_nl,t] = non_linearized_and_linearized_simulation(app,A,B,C,f,h,x,x_nl,K,mu_w,mu_v,sigma_w,sigma_v,dt,w,v,w_nl,v_nl,tempo_simulacao,X,Y,U,t,X_nl,Y_nl,U_nl)
%                            
                         elseif incluir_ruido_sistema == 0 && incluir_ruido_sensor == 0
                             %% sistemas(linear, nao_linear,lqr)
                            [Y,Y_nl,X,X_nl,U,U_nl,t] = non_linearized_and_linearized_simulation(app,A,B,C,f,h,x,x_nl,K,0,0,0,0,dt,0,0,0,0,tempo_simulacao,X,Y,U,t,X_nl,Y_nl,U_nl)
        %                   [Y,Y_nl,X,X_nl,U,U_nl,t] = non_linearized_and_linearized_simulation(app,A,B,C,f,h,x,x_nl,K,mu_w,mu_v,sigma_w,sigma_v,dt,w,v,w_nl,v_nl,tempo_simulacao,X,Y,U,t,X_nl,Y_nl,U_nl)
                         end 
                     elseif incluir_controle_lqr == 0   
                        K = [0 0 0 0] 
                        if incluir_ruido_sistema == 1 && incluir_ruido_sensor == 1    
                             %% sistema(nao_linear,lqr,ruido_w,ruido_v)
                             [Y,Y_nl,X,X_nl,U,U_nl,t] = non_linearized_and_linearized_simulation(app,A,B,C,f,h,x,x_nl,K,mu_w,mu_v,sigma_w,sigma_v,dt,w,v,w_nl,v_nl,tempo_simulacao,X,Y,U,t,X_nl,Y_nl,U_nl)
%                            [Y,Y_nl,X,X_nl,U,U_nl,t] = non_linearized_and_linearized_simulation(app,A,B,C,f,h,x,x_nl,K,mu_w,mu_v,sigma_w,sigma_v,dt,w,v,w_nl,v_nl,tempo_simulacao,X,Y,U,t,X_nl,Y_nl,U_nl)
%    
                         elseif incluir_ruido_sistema == 1 && incluir_ruido_sensor == 0
                             %% sistema(nao_linear,lqr,ruido_w)
                             [Y,Y_nl,X,X_nl,U,U_nl,t] = non_linearized_and_linearized_simulation(app,A,B,C,f,h,x,x_nl,K,mu_w,0,sigma_w,0,dt,w,0,w_nl,0,tempo_simulacao,X,Y,U,t,X_nl,Y_nl,U_nl)
%                            [Y,Y_nl,X,X_nl,U,U_nl,t] = non_linearized_and_linearized_simulation(app,A,B,C,f,h,x,x_nl,K,mu_w,mu_v,sigma_w,sigma_v,dt,w,v,w_nl,v_nl,tempo_simulacao,X,Y,U,t,X_nl,Y_nl,U_nl)
%         
                         elseif incluir_ruido_sistema == 0 && incluir_ruido_sensor == 1
                             %% sistema(nao_linear,lqr,ruido_w)
                             [Y,Y_nl,X,X_nl,U,U_nl,t] = non_linearized_and_linearized_simulation(app,A,B,C,f,h,x,x_nl,K,0,mu_v,0,sigma_v,dt,0,v,0,v_nl,tempo_simulacao,X,Y,U,t,X_nl,Y_nl,U_nl)
%                            [Y,Y_nl,X,X_nl,U,U_nl,t] = non_linearized_and_linearized_simulation(app,A,B,C,f,h,x,x_nl,K,mu_w,mu_v,sigma_w,sigma_v,dt,w,v,w_nl,v_nl,tempo_simulacao,X,Y,U,t,X_nl,Y_nl,U_nl)
%                            
                         elseif incluir_ruido_sistema == 0 && incluir_ruido_sensor == 0
                             %% sistemas(linear, nao_linear,lqr)
                            [Y,Y_nl,X,X_nl,U,U_nl,t] = non_linearized_and_linearized_simulation(app,A,B,C,f,h,x,x_nl,K,0,0,0,0,dt,0,0,0,0,tempo_simulacao,X,Y,U,t,X_nl,Y_nl,U_nl)
        %                   [Y,Y_nl,X,X_nl,U,U_nl,t] = non_linearized_and_linearized_simulation(app,A,B,C,f,h,x,x_nl,K,mu_w,mu_v,sigma_w,sigma_v,dt,w,v,w_nl,v_nl,tempo_simulacao,X,Y,U,t,X_nl,Y_nl,U_nl)
                        end        
                     end
                     
                     app.Y = Y;  
                     app.X = X;
                     app.Y_nl = Y_nl;  
                     app.X_nl = X_nl;
                     
                     set(app.grafico_covariancia,'Visible','off')
                     set(app.grafico_erro_estimacao,'Visible','off')                                       
                     legend(app.grafico_erro_estimacao,'hide')
                     legend(app.grafico_covariancia,'hide')
                     
                     plot(app.grafico_posicao_carrinho,t,Y(1,:)); 
                     hold(app.grafico_posicao_carrinho,'on');
                     plot(app.grafico_posicao_carrinho,t,Y_nl(1,:)); 
                     legend(app.grafico_posicao_carrinho, 'Linearizada','Nao-linear')   
                     hold(app.grafico_posicao_carrinho,'off');
                     
                     plot(app.grafico_angulo_haste,t,(180/pi)*Y(2,:)); 
                     hold(app.grafico_angulo_haste,'on');
                     plot(app.grafico_angulo_haste,t,(180/pi)*Y_nl(2,:)); 
                     legend(app.grafico_angulo_haste, 'Linearizado','Nao-linear')   
                     hold(app.grafico_angulo_haste,'off');
                     
                     plot(app.grafico_velocidades,t,X(2,:)); 
                     hold(app.grafico_velocidades,'on');
                     plot(app.grafico_velocidades,t,X_nl(2,:)); 
                     plot(app.grafico_velocidades,t,X(4,:)); 
                     plot(app.grafico_velocidades,t,X_nl(4,:)); 
                     legend(app.grafico_velocidades, 'Velocidade do carrinho - Linearizado','Velocidade do carrinho - Não-linear','Velocidade do pêndulo - Linearizado','Velocidade do pêndulo - Não-linear')   
                     hold(app.grafico_velocidades,'off');
                     
                     plot(app.grafico_sinal_controle,t,U);
                     hold(app.grafico_sinal_controle,'on');
                     plot(app.grafico_sinal_controle,t,U_nl);
                     legend(app.grafico_sinal_controle, 'Linearizado','Não-linear') 
                     hold(app.grafico_sinal_controle,'off');

                         
                     if app.mostrar_janela.Value == 1                        
                    
                     fig = uifigure;
                     tl= tiledlayout(fig,2,2,'TileSpacing',"compact",'Padding',"compact");
                     title(tl,'Simulação Conjunta dos Sistemas Linearizado e Não-Linear')
                    
                                                         
                     grafico_posicao_carrinho = nexttile(tl)  
                     plot(grafico_posicao_carrinho,t,Y(1,:)); 
                     hold(grafico_posicao_carrinho,'on');
                     plot(grafico_posicao_carrinho,t,Y_nl(1,:)); 
                     legend(grafico_posicao_carrinho, 'Linearizada','Nao-linear')   
                     hold(grafico_posicao_carrinho,'off');
                     title(grafico_posicao_carrinho,'Gráfico da Posição do Carrinho vs. Tempo')
                     ylabel(grafico_posicao_carrinho,'Posição do Carrinho - m')
                     xlabel(grafico_posicao_carrinho,'Tempo - s')
                     grid(grafico_posicao_carrinho,'on');
                     l1 = legend(grafico_posicao_carrinho); 
                     l1.ButtonDownFcn = {@app.legenda,grafico_posicao_carrinho};
                     
                     
                     grafico_angulo_haste = nexttile(tl)
                     plot(grafico_angulo_haste,t,(180/pi)*Y(2,:)); 
                     hold(grafico_angulo_haste,'on');
                     plot(grafico_angulo_haste,t,(180/pi)*Y_nl(2,:)); 
                     legend(grafico_angulo_haste, 'Linearizado','Nao-linear')   
                     hold(grafico_angulo_haste,'off');
                     title(grafico_angulo_haste,'Gráfico do Ângulo da Haste vs. Tempo')
                     ylabel(grafico_angulo_haste,'Ângulo da Haste - º')
                     xlabel(grafico_angulo_haste,'Tempo - s')
                     grid(grafico_angulo_haste,'on');
                     l2 = legend(grafico_angulo_haste); 
                     l2.ButtonDownFcn = {@app.legenda,grafico_angulo_haste};
                     
                     grafico_velocidades = nexttile(tl)
                     plot(grafico_velocidades,t,X(2,:)); 
                     hold(grafico_velocidades,'on');
                     plot(grafico_velocidades,t,X_nl(2,:)); 
                     plot(grafico_velocidades,t,X(4,:)); 
                     plot(grafico_velocidades,t,X_nl(4,:)); 
                     legend(grafico_velocidades, 'Velocidade do carrinho - Linearizado','Velocidade do carrinho - Não-linear','Velocidade do pêndulo - Linearizado','Velocidade do pêndulo - Não-linear')   
                     hold(grafico_velocidades,'off');
                     title(grafico_velocidades,'Gráfico de Velocidades linear e angular vs. Tempo')
                     ylabel(grafico_velocidades,'velocidades')
                     xlabel(grafico_velocidades,'Tempo - s')
                     grid(grafico_velocidades,'on');
                     l3 = legend(grafico_velocidades); 
                     l3.ButtonDownFcn = {@app.legenda,grafico_velocidades};
                     
                     grafico_sinal_controle = nexttile(tl)                     
                     plot(grafico_sinal_controle,t,U);
                     hold(grafico_sinal_controle,'on');
                     plot(grafico_sinal_controle,t,U_nl);
                     legend(grafico_sinal_controle, 'Linearizado','Não-linear') 
                     hold(grafico_sinal_controle,'off');
                     title(grafico_sinal_controle,'Gráfico do Sinal de Controle vs. Tempo')
                     ylabel(grafico_sinal_controle,'Sinal de Costrole')
                     xlabel(grafico_sinal_controle,'Tempo - s')
                     grid(grafico_sinal_controle,'on');
                     l4 = legend(grafico_sinal_controle); 
                     l4.ButtonDownFcn = {@app.legenda,grafico_sinal_controle};
                     end 
       
                end
            elseif filtrar == 2
                if KF == 1
                    if incluir_controle_lqr == 1
                        if incluir_ruido_sistema == 1 && incluir_ruido_sensor == 1
                             %% sistema(FK,sistema_linearizado,lqr,w,v)
                             [Y,Yhat,X,Xhat,Px,U,Yt,t,L_FK] = kalman(app,A,B,C,x,xhat,K,Q_w,Q_v,mu_w,mu_v,sigma_w,sigma_v,P,dt,w,v,tempo_simulacao,X,Y,Xhat,Yhat,U,t,Px,Yt,L_FK)

                        elseif incluir_ruido_sistema == 1 && incluir_ruido_sensor == 0
                             %% sistema(FK,sistema_linearizado,lqr,w)
                             [Y,Yhat,X,Xhat,Px,U,Yt,t,L_FK] = kalman(app,A,B,C,x,xhat,K,Q_w,Q_v,mu_w,0,sigma_w,0,P,dt,w,0,tempo_simulacao,X,Y,Xhat,Yhat,U,t,Px,Yt,L_FK)

                        elseif incluir_ruido_sistema == 0 && incluir_ruido_sensor == 1
                             %% sistema(FK,sistema_linearizado,lqr,v)
                             [Y,Yhat,X,Xhat,Px,U,Yt,t,L_FK] = kalman(app,A,B,C,x,xhat,K,Q_w,Q_v,0,mu_v,0,sigma_v,P,dt,0,v,tempo_simulacao,X,Y,Xhat,Yhat,U,t,Px,Yt,L_FK)
                               
                        elseif incluir_ruido_sistema == 0 && incluir_ruido_sensor == 0
                             %% sistema(FK,sistema_linearizado,lqr)
                             [Y,Yhat,X,Xhat,Px,U,Yt,t,L_FK] = kalman(app,A,B,C,x,xhat,K,Q_w,Q_v,0,0,0,0,P,dt,0,0,tempo_simulacao,X,Y,Xhat,Yhat,U,t,Px,Yt,L_FK)         
                        end
                    else
                        %% sistema(FK,sistema_linearizado)
                        K = [0 0 0 0]
                        if incluir_ruido_sistema == 1 && incluir_ruido_sensor == 1
                             %% sistema(FK,sistema_linearizado,w,v)
                             [Y,Yhat,X,Xhat,Px,U,Yt,t,L_FK] = kalman(app,A,B,C,x,xhat,K,Q_w,Q_v,mu_w,mu_v,sigma_w,sigma_v,P,dt,w,v,tempo_simulacao,X,Y,Xhat,Yhat,U,t,Px,Yt,L_FK)

                        elseif incluir_ruido_sistema == 1 && incluir_ruido_sensor == 0
                             %% sistema(FK,sistema_linearizado,w)
                             [Y,Yhat,X,Xhat,Px,U,Yt,t,L_FK] = kalman(app,A,B,C,x,xhat,K,Q_w,Q_v,mu_w,0,sigma_w,0,P,dt,w,0,tempo_simulacao,X,Y,Xhat,Yhat,U,t,Px,Yt,L_FK)

                        elseif incluir_ruido_sistema == 0 && incluir_ruido_sensor == 1
                             %% sistema(FK,sistema_linearizado,v)
                             [Y,Yhat,X,Xhat,Px,U,Yt,t,L_FK] = kalman(app,A,B,C,x,xhat,K,Q_w,Q_v,0,mu_v,0,sigma_v,P,dt,0,v,tempo_simulacao,X,Y,Xhat,Yhat,U,t,Px,Yt,L_FK)
                               
                        elseif incluir_ruido_sistema == 0 && incluir_ruido_sensor == 0
                             %% sistema(FK,sistema_linearizado)
                             [Y,Yhat,X,Xhat,Px,U,Yt,t,L_FK] = kalman(app,A,B,C,x,xhat,K,Q_w,Q_v,0,0,0,0,P,dt,0,0,tempo_simulacao,X,Y,Xhat,Yhat,U,t,Px,Yt,L_FK)         
                        end                      
                    end
                    
                    app.Y = Y;  
                    app.X = X;
                    app.Yhat = Yhat;  
                    app.Xhat = Xhat;
                    app.L_FK = L_FK;
                   
                     
                    set(app.grafico_covariancia,'Visible','on')
                    set(app.grafico_erro_estimacao,'Visible','on')
                    legend(app.grafico_sinal_controle,'hide')
                    
                    plot(app.grafico_posicao_carrinho,t,X(1,:));
                    hold(app.grafico_posicao_carrinho,'on');
                    plot(app.grafico_posicao_carrinho,t,Y(1,:));
                    plot(app.grafico_posicao_carrinho,t,Yhat(1,:));
                    legend(app.grafico_posicao_carrinho, 'Real','Medida','Estimada')
                    hold(app.grafico_posicao_carrinho,'off');
                    
                    plot(app.grafico_angulo_haste,t,(180/pi)*X(3,:))
                    hold(app.grafico_angulo_haste,'on');
                    plot(app.grafico_angulo_haste,t,(180/pi)*Y(2,:))
                    plot(app.grafico_angulo_haste,t,(180/pi)*Yhat(2,:))
                    legend(app.grafico_angulo_haste, 'Real','Medido','Estimado')
                    hold(app.grafico_angulo_haste,'off');
                    
                    plot(app.grafico_velocidades,t,X(2,:));
                    hold(app.grafico_velocidades,'on');
                    plot(app.grafico_velocidades,t,Xhat(2,:));
                    plot(app.grafico_velocidades,t,X(4,:));
                    plot(app.grafico_velocidades,t,Xhat(4,:));
                    legend(app.grafico_velocidades, 'Velocidade Carrinho - Real','Velocidade Carrinho - Estimada','Velocidade Pêndulo - Real','Velocidade Pêndulo - Estimada')
                    hold(app.grafico_velocidades,'off');
                    
                    plot(app.grafico_sinal_controle,t,U);
                    title(app.grafico_sinal_controle,'Gráfico do Sinal de Controle vs. Tempo')
                    
                    plot(app.grafico_covariancia,t,Px);
                    legend(app.grafico_covariancia, 'Posição Carrinho', 'Velocidade Carrinho','Ângulo Pêndulo', 'Velocidade Pêndulo')

                    plot(app.grafico_erro_estimacao,t,Yt);
                    legend(app.grafico_erro_estimacao, 'Posição','Ângulo') 

                    
                    if app.mostrar_janela.Value == 1                        
                    
                    fig = uifigure;
                    tl= tiledlayout(fig,3,2,'TileSpacing',"compact",'Padding',"compact");
                    title(tl,'Simulação do Sistema Filtro De Kalman')
                  
                    
                    grafico_posicao_carrinho = nexttile(tl)
                    plot(grafico_posicao_carrinho,t,X(1,:));
                    hold(grafico_posicao_carrinho,'on');
                    plot(grafico_posicao_carrinho,t,Y(1,:));
                    plot(grafico_posicao_carrinho,t,Yhat(1,:));
                    legend(grafico_posicao_carrinho, 'Real','Medida','Estimada')
                    hold(grafico_posicao_carrinho,'off');
                    title(grafico_posicao_carrinho,'Gráfico da Posição do Carrinho vs. Tempo')
                    ylabel(grafico_posicao_carrinho,'Posição do Carrinho - m')
                    xlabel(grafico_posicao_carrinho,'Tempo - s')
                    grid(grafico_posicao_carrinho,'on');
                    l1 = legend(grafico_posicao_carrinho); 
                    l1.ButtonDownFcn = {@app.legenda,grafico_posicao_carrinho};
                    
                    grafico_angulo_haste = nexttile(tl)
                    plot(grafico_angulo_haste,t,(180/pi)*X(3,:))
                    hold(grafico_angulo_haste,'on');
                    plot(grafico_angulo_haste,t,(180/pi)*Y(2,:))
                    plot(grafico_angulo_haste,t,(180/pi)*Yhat(2,:))
                    legend(grafico_angulo_haste, 'Real','Medido','Estimado')
                    hold(grafico_angulo_haste,'off');
                    title(grafico_angulo_haste,'Gráfico do Ângulo da Haste vs. Tempo')
                    ylabel(grafico_angulo_haste,'Ângulo da Haste - º')
                    xlabel(grafico_angulo_haste,'Tempo - s')
                    grid(grafico_angulo_haste,'on');
                    l2 = legend(grafico_angulo_haste); 
                    l2.ButtonDownFcn = {@app.legenda,grafico_angulo_haste};
                    
                    grafico_velocidades = nexttile(tl)
                    plot(grafico_velocidades,t,X(2,:));
                    hold(grafico_velocidades,'on');
                    plot(grafico_velocidades,t,Xhat(2,:));
                    plot(grafico_velocidades,t,X(4,:));
                    plot(grafico_velocidades,t,Xhat(4,:));
                    legend(grafico_velocidades, 'Velocidade Carrinho - Real','Velocidade Carrinho - Estimada','Velocidade Pêndulo - Real','Velocidade Pêndulo - Estimada')
                    hold(grafico_velocidades,'off');
                    title(grafico_velocidades,'Gráfico de Velocidades linear e angular vs. Tempo')
                    ylabel(grafico_velocidades,'Carrinho - (m/s)   Haste - (rad/s)')
                    xlabel(grafico_velocidades,'Tempo - s')
                    grid(grafico_velocidades,'on');
                    l3 = legend(grafico_velocidades); 
                    l3.ButtonDownFcn = {@app.legenda,grafico_velocidades};
                    
                    grafico_sinal_controle = nexttile(tl)
                    plot(grafico_sinal_controle,t,U);
                    title(grafico_sinal_controle,'Gráfico do Sinal de Controle vs. Tempo')
                    ylabel(grafico_sinal_controle,'Sinal de Controle')
                    xlabel(grafico_sinal_controle,'Tempo - s')
                    grid(grafico_sinal_controle,'on');
%                   l4 = legend(grafico_sinal_controle); 
%                   l4.ButtonDownFcn = {@app.legenda,grafico_sinal_controle};
                    
                                        
                    grafico_covariancia = nexttile(tl)
                    plot(grafico_covariancia,t,Px);
                    legend(grafico_covariancia, 'Posição Carrinho', 'Velocidade Carrinho','Ângulo Pêndulo', 'Velocidade Pêndulo')
                    title(grafico_covariancia,'Gráfico da Covariância do Erro vs. Tempo')
                    ylabel(grafico_covariancia,'Covariância')
                    xlabel(grafico_covariancia,'Tempo - s')
                    grid(grafico_covariancia,'on');
                    l5 = legend(grafico_covariancia); 
                    l5.ButtonDownFcn = {@app.legenda,grafico_covariancia};
                    
                    
                    grafico_erro_estimacao = nexttile(tl)
                    plot(grafico_erro_estimacao,t,Yt);
                    legend(grafico_erro_estimacao, 'Posição','Ângulo') 
                    title(grafico_erro_estimacao,'Gráfico do Erro de Estimação vs. Tempo')
                    ylabel(grafico_erro_estimacao,'Erro de Estimação')
                    xlabel(grafico_erro_estimacao,'Tempo - s')
                    grid(grafico_erro_estimacao,'on');
                    l6 = legend(grafico_erro_estimacao); 
                    l6.ButtonDownFcn = {@app.legenda,grafico_erro_estimacao};
                    
                    end 
                elseif EKF == 1
                    if incluir_controle_lqr == 1
                        if incluir_ruido_sistema == 1 && incluir_ruido_sensor == 1
                            %% sistema(EKF,sistema_nao_linear,lqr,w,v) 
                            [Y,Yhat,X,Xhat,Px,U,Yt,t,L_EKF] = extended_kalman(app,C,f,h,F_j,x,xhat,K,Q_w,Q_v,mu_w,mu_v,sigma_w,sigma_v,P,dt,w,v,tempo_simulacao,X,Y,Xhat,Yhat,U,t,Px,Yt,L_EKF)
                              
                        elseif incluir_ruido_sistema == 1 && incluir_ruido_sensor == 0
                            %% sistema(EKF,sistema_nao_linear,lqr,w)
                            [Y,Yhat,X,Xhat,Px,U,Yt,t,L_EKF] = extended_kalman(app,C,f,h,F_j,x,xhat,K,Q_w,Q_v,mu_w,0,sigma_w,0,P,dt,w,0,tempo_simulacao,X,Y,Xhat,Yhat,U,t,Px,Yt,L_EKF)
                           
                        elseif incluir_ruido_sistema == 0 && incluir_ruido_sensor == 1
                            %% sistema(EKF,sistema_nao_linear,lqr,v)
                            [Y,Yhat,X,Xhat,Px,U,Yt,t,L_EKF] = extended_kalman(app,C,f,h,F_j,x,xhat,K,Q_w,Q_v,0,mu_v,0,sigma_v,P,dt,0,v,tempo_simulacao,X,Y,Xhat,Yhat,U,t,Px,Yt,L_EKF)                                                       
                            
                        elseif incluir_ruido_sistema == 0 && incluir_ruido_sensor == 0
                            %% sistema(EKF,sistema_nao_linear,lqr)
                            [Y,Yhat,X,Xhat,Px,U,Yt,t,L_EKF] = extended_kalman(app,C,f,h,F_j,x,xhat,K,Q_w,Q_v,0,0,0,0,P,dt,0,0,tempo_simulacao,X,Y,Xhat,Yhat,U,t,Px,Yt,L_EKF)
                        end
                    else
                        %% sistema(EKF,sistema_nao_linear)
                        K = [0 0 0 0]
                        if incluir_ruido_sistema == 1 && incluir_ruido_sensor == 1
                            %% sistema(EKF,sistema_nao_linear,lqr,w,v) 
                            [Y,Yhat,X,Xhat,Px,U,Yt,t,L_EKF] = extended_kalman(app,C,f,h,F_j,x,xhat,K,Q_w,Q_v,mu_w,mu_v,sigma_w,sigma_v,P,dt,w,v,tempo_simulacao,X,Y,Xhat,Yhat,U,t,Px,Yt,L_EKF)
                                
                        elseif incluir_ruido_sistema == 1 && incluir_ruido_sensor == 0
                            %% sistema(EKF,sistema_nao_linear,lqr,w)
                            [Y,Yhat,X,Xhat,Px,U,Yt,t,L_EKF] = extended_kalman(app,C,f,h,F_j,x,xhat,K,Q_w,Q_v,mu_w,0,sigma_w,0,P,dt,w,0,tempo_simulacao,X,Y,Xhat,Yhat,U,t,Px,Yt,L_EKF)
                           
                        elseif incluir_ruido_sistema == 0 && incluir_ruido_sensor == 1
                            %% sistema(EKF,sistema_nao_linear,lqr,v)
                            [Y,Yhat,X,Xhat,Px,U,Yt,t,L_EKF] = extended_kalman(app,C,f,h,F_j,x,xhat,K,Q_w,Q_v,0,mu_v,0,sigma_v,P,dt,0,v,tempo_simulacao,X,Y,Xhat,Yhat,U,t,Px,Yt,L_EKF)                                                       
                            
                        elseif incluir_ruido_sistema == 0 && incluir_ruido_sensor == 0
                            %% sistema(EKF,sistema_nao_linear,lqr)
                            [Y,Yhat,X,Xhat,Px,U,Yt,t,L_EKF] = extended_kalman(app,C,f,h,F_j,x,xhat,K,Q_w,Q_v,0,0,0,0,P,dt,0,0,tempo_simulacao,X,Y,Xhat,Yhat,U,t,Px,Yt,L_EKF)
                        end    
                    end
               
                app.Y = Y;  
                app.X = X;
                app.Yhat = Yhat;  
                app.Xhat = Xhat;
                app.L_EKF = L_EKF;
                
                set(app.grafico_covariancia,'Visible','on')
                set(app.grafico_erro_estimacao,'Visible','on')
                legend(app.grafico_sinal_controle,'hide')
                
                plot(app.grafico_posicao_carrinho,t,X(1,:));
                hold(app.grafico_posicao_carrinho,'on');
                plot(app.grafico_posicao_carrinho,t,Y(1,:));
                plot(app.grafico_posicao_carrinho,t,Yhat(1,:));
                legend(app.grafico_posicao_carrinho, 'Real','Medido','Estimado')
                hold(app.grafico_posicao_carrinho,'off');
                
                plot(app.grafico_angulo_haste,t,(180/pi)*X(3,:))
                hold(app.grafico_angulo_haste,'on');
                plot(app.grafico_angulo_haste,t,(180/pi)*Y(2,:))
                plot(app.grafico_angulo_haste,t,(180/pi)*Yhat(2,:))
                legend(app.grafico_angulo_haste, 'Real','Medido','Estimado')
                hold(app.grafico_angulo_haste,'off');
                
                plot(app.grafico_velocidades,t,X(2,:));
                hold(app.grafico_velocidades,'on');
                plot(app.grafico_velocidades,t,Xhat(2,:));
                plot(app.grafico_velocidades,t,X(4,:));
                plot(app.grafico_velocidades,t,Xhat(4,:));
                legend(app.grafico_velocidades, 'Velocidade Carrinho - Real','Velocidade Carrinho - Estimada','Velocidade Pêndulo - Real','Velocidade Pêndulo - Estimada')
                hold(app.grafico_velocidades,'off');
                
                plot(app.grafico_sinal_controle,t,U);
                
                plot(app.grafico_covariancia,t,Px);
                legend(app.grafico_covariancia, 'Posição Carrinho', 'Velocidade Carrinho','Ângulo Pêndulo', 'Velocidade Pêndulo')

                plot(app.grafico_erro_estimacao,t,Yt);
                legend(app.grafico_erro_estimacao, 'Posição','Ângulo') 
                
                if app.mostrar_janela.Value == 1                        
                    
                fig = uifigure;
                tl= tiledlayout(fig,3,2,'TileSpacing',"compact",'Padding',"compact");
                title(tl,'Simulação do Sistema com Filtro de Kalman Extendido')
   
                                
                grafico_posicao_carrinho = nexttile(tl)
                plot(grafico_posicao_carrinho,t,X(1,:));
                hold(grafico_posicao_carrinho,'on');
                plot(grafico_posicao_carrinho,t,Y(1,:));
                plot(grafico_posicao_carrinho,t,Yhat(1,:));
                legend(grafico_posicao_carrinho, 'Real','Medido','Estimado')
                hold(grafico_posicao_carrinho,'off');
                title(grafico_posicao_carrinho,'Gráfico da Posição do Carrinho vs. Tempo')
                ylabel(grafico_posicao_carrinho,'Posição do Carrinho - m')
                xlabel(grafico_posicao_carrinho,'Tempo - s')
                grid(grafico_posicao_carrinho,'on');
                l1 = legend(grafico_posicao_carrinho); 
                l1.ButtonDownFcn = {@app.legenda,grafico_posicao_carrinho};
                
                grafico_angulo_haste = nexttile(tl)
                plot(grafico_angulo_haste,t,(180/pi)*X(3,:))
                hold(grafico_angulo_haste,'on');
                plot(grafico_angulo_haste,t,(180/pi)*Y(2,:))
                plot(grafico_angulo_haste,t,(180/pi)*Yhat(2,:))
                legend(grafico_angulo_haste, 'Real','Medido','Estimado')
                hold(grafico_angulo_haste,'off');
                title(grafico_angulo_haste,'Gráfico do Ângulo da Haste vs. Tempo')
                ylabel(grafico_angulo_haste,'Ângulo da Haste - º')
                xlabel(grafico_angulo_haste,'Tempo - s')
                grid(grafico_angulo_haste,'on');
                l2 = legend(grafico_angulo_haste); 
                l2.ButtonDownFcn = {@app.legenda,grafico_angulo_haste};
                
               
                grafico_velocidades = nexttile(tl)
                plot(grafico_velocidades,t,X(2,:));
                hold(grafico_velocidades,'on');
                plot(grafico_velocidades,t,Xhat(2,:));
                plot(grafico_velocidades,t,X(4,:));
                plot(grafico_velocidades,t,Xhat(4,:));
                legend(grafico_velocidades, 'Velocidade Carrinho - Real','Velocidade Carrinho - Estimada','Velocidade Pêndulo - Real','Velocidade Pêndulo - Estimada')
                hold(grafico_velocidades,'off');
                title(grafico_velocidades,'Gráfico de Velocidades linear e angular vs. Tempo')
                ylabel(grafico_velocidades,'Carrinho - (m/s)  Haste - (rad/s)')
                xlabel(grafico_velocidades,'Tempo - s')
                grid(grafico_velocidades,'on');
                l3 = legend(grafico_velocidades); 
                l3.ButtonDownFcn = {@app.legenda,grafico_velocidades};
                
                
                grafico_sinal_controle = nexttile(tl)
                plot(grafico_sinal_controle,t,U);
                title(grafico_sinal_controle,'Gráfico do Sinal de Controle vs. Tempo')
                ylabel(grafico_sinal_controle,'Sinal de Costrole')
                xlabel(grafico_sinal_controle,'Tempo - s')
                grid(grafico_sinal_controle,'on');
%                 l4 = legend(grafico_sinal_controle); 
%                 l4.ButtonDownFcn = {@app.legenda,grafico_sinal_controle};
                
                grafico_covariancia = nexttile(tl)
                plot(grafico_covariancia,t,Px);
                legend(grafico_covariancia, 'Posição Carrinho', 'Velocidade Carrinho','Ângulo Pêndulo', 'Velocidade Pêndulo')
                title(grafico_covariancia,'Gráfico da Covariância do Erro vs. Tempo')
                ylabel(grafico_covariancia,'Covariância')
                xlabel(grafico_covariancia,'Tempo - s')
                grid(grafico_covariancia,'on');
                l5 = legend(grafico_covariancia); 
                l5.ButtonDownFcn = {@app.legenda,grafico_covariancia};
                
                grafico_erro_estimacao = nexttile(tl)
                plot(grafico_erro_estimacao,t,Yt);
                legend(grafico_erro_estimacao, 'Posição','Ângulo') 
                title(grafico_erro_estimacao,'Gráfico do Erro de Estimação vs. Tempo')
                ylabel(grafico_erro_estimacao,'Erro de Estimação')
                xlabel(grafico_erro_estimacao,'Tempo - s')
                grid(grafico_erro_estimacao,'on');
                l6 = legend(grafico_erro_estimacao); 
                l6.ButtonDownFcn = {@app.legenda,grafico_erro_estimacao};
                end

            end
            end

            %% Autovalores de malha aberta e malha fechada
            av1  = eig(A)
            app.av1.Text = num2str(av1(1))
            app.av2.Text = num2str(av1(2))
            app.av3.Text = num2str(av1(3))
            app.av4.Text = num2str(av1(4))
            av2  = eig(A-B*K)
            app.av11.Text = num2str(av2(1))
            app.av22.Text = num2str(av2(2))
            app.av33.Text = num2str(av2(3))
            app.av44.Text = num2str(av2(4))
%             plot(app.grafico_polos_ma,real(av1),imag(av1),'r*');
%             plot(app.grafico_polos_mf,real(av2),imag(av2),'r*');
            
            %% Mostrando os valores de K
            app.k1.Text = num2str(K(1))
            app.k2.Text = num2str(K(2))
            app.k3.Text = num2str(K(3))
            app.k4.Text = num2str(K(4))
            
            %% Habilitando o botão de animação
            app.button_animacao.Enable = 'on';
            
            %% Habilitar botão que mostra o filtro de Kalman
            if isempty(app.L_EKF) && isempty(app.L_FK)
              app.btn_visualizar_ganho_kalman.Enable = 'off'
            elseif ~isempty(app.L_EKF) || ~isempty(app.L_FK)
              app.btn_visualizar_ganho_kalman.Enable = 'on'
            end
            
            %% Inclui a função selecionar linha através da legenda
            if filtrar == 1     
                  if linearizado == 1 && nao_linear == 1
                     l1 = legend(app.grafico_posicao_carrinho); 
                     l2 = legend(app.grafico_angulo_haste); 
                     l3 = legend(app.grafico_velocidades); 
                     l5 = legend(app.grafico_sinal_controle);                      
                     l1.ButtonDownFcn = {@app.legenda,app.grafico_posicao_carrinho};
                     l2.ButtonDownFcn = {@app.legenda,app.grafico_angulo_haste};
                     l3.ButtonDownFcn = {@app.legenda,app.grafico_velocidades};
                     l5.ButtonDownFcn = {@app.legenda,app.grafico_sinal_controle}                                                              
                  elseif linearizado == 1 || nao_linear == 1                                        
                     l3 = legend(app.grafico_velocidades); 
                     l3.ButtonDownFcn = {@app.legenda,app.grafico_velocidades};                   
                 end
                   
            elseif filtrar == 2 
                  l1 = legend(app.grafico_posicao_carrinho); 
                  l2 = legend(app.grafico_angulo_haste); 
                  l3 = legend(app.grafico_velocidades); 
                  l4 = legend(app.grafico_covariancia);                  
                  l6 = legend(app.grafico_erro_estimacao); 
                  
                  l1.ButtonDownFcn = {@app.legenda,app.grafico_posicao_carrinho};
                  l2.ButtonDownFcn = {@app.legenda,app.grafico_angulo_haste};
                  l3.ButtonDownFcn = {@app.legenda,app.grafico_velocidades};
                  l4.ButtonDownFcn = {@app.legenda,app.grafico_covariancia};                 
                  l6.ButtonDownFcn = {@app.legenda,app.grafico_erro_estimacao};
                                                 
            end

            
        end

        % Value changed function: incluir_ruido_sistema
        function incluir_ruido_sistemaValueChanged(app, event)
            value = app.incluir_ruido_sistema.Value;
            
            if value == 1
                set(app.simulacao_variancia_w,'Enable','on')
                set(app.simulacao_variancia_label_w,'Enable','on')
                set(app.simulacao_media_w,'Enable','on')
                set(app.simulacao_media_label_w,'Enable','on')
            else
                set(app.simulacao_variancia_w,'Enable','off')
                set(app.simulacao_variancia_label_w,'Enable','off')
                set(app.simulacao_media_w,'Enable','off')
                set(app.simulacao_media_label_w,'Enable','off')
            end
            
        end

        % Value changed function: incluir_ruido_sensor
        function incluir_ruido_sensorValueChanged(app, event)
           
           value = app.incluir_ruido_sensor.Value;
            
            if value == 1
                set(app.simulacao_variancia_v,'Enable','on')
                set(app.simulacao_variancia_label_v,'Enable','on')
                set(app.simulacao_media_v,'Enable','on')
                set(app.simulacao_media_label_v,'Enable','on')
            else
                set(app.simulacao_variancia_v,'Enable','off')
                set(app.simulacao_variancia_label_v,'Enable','off')
                set(app.simulacao_media_v,'Enable','off')
                set(app.simulacao_media_label_v,'Enable','off')
            end 
        end

        % Button pushed function: PoloseZerosButton
        function PoloseZerosButtonPushed(app, event)
              
            %% Matrizes das Equações de Estados Linearizadas      
            A = [ 0 1 0 0; 0 0 -(app.g*app.m)/app.M 0; 0 0 0 1; 0 0 -(app.g*(app.M + app.m))/(-app.l*app.M) 0];
            B = [0; 1/app.M; 0; -(1/(app.l*app.M))];
            C = [1 0 0 0; 0 0 1 0];
            D = [0;0];
            
            %% Recebendo os valores de Q e R
            q_x = app.x.Value;
            q_dotx = app.dotx.Value;
            q_alpha = app.alpha.Value;
            q_dotalpha = app.dotalpha.Value;
            R = app.r.Value
            
            %% Matriz Q e R
            Q = [q_x 0 0 0;0 q_dotx 0 0;0 0 q_alpha 0; 0 0 0 q_dotalpha]
            K = lqr( A, B, Q, R)  % Matriz de Ganho de realimentação
            
            sys = ss(A,B,C,D);
            sys_lqr = ss(A-B*K,B,C,D)
            
            fig = uifigure  
%             set(fig,'WindowState','Maximized')
            fig.AutoResizeChildren = 'off';    
            
            if app.checkbox_incluir_controle.Value == 1
                 %% Autovalores de malha aberta e malha fechada
            av1  = eig(A)
            app.av1.Text = num2str(av1(1))
            app.av2.Text = num2str(av1(2))
            app.av3.Text = num2str(av1(3))
            app.av4.Text = num2str(av1(4))
            av2  = eig(A-B*K)
            app.av11.Text = num2str(av2(1))
            app.av22.Text = num2str(av2(2))
            app.av33.Text = num2str(av2(3))
            app.av44.Text = num2str(av2(4))
%             plot(app.grafico_polos_ma,real(av1),imag(av1),'r*');
%             plot(app.grafico_polos_mf,real(av2),imag(av2),'r*');
            
            %% Mostrando os valores de K
            app.k1.Text = num2str(K(1))
            app.k2.Text = num2str(K(2))
            app.k3.Text = num2str(K(3))
            app.k4.Text = num2str(K(4))
            grafico_sys = subplot(1,2,1,'Parent',fig);
            grafico_sys_lqr = subplot(1,2,2,'Parent',fig);          
            p1 = pzplot(grafico_sys,sys)           
            p2 = pzplot(grafico_sys_lqr,sys_lqr)    
            grid(grafico_sys,"on")
            grid(grafico_sys_lqr,"on")
            title(grafico_sys,'Polos de A')
            title(grafico_sys_lqr,'Polos de A-BK')
            xlabel(grafico_sys,'Eixo Real - Re')
            ylabel(grafico_sys,'Eixo Imaginário - Im');
            xlabel(grafico_sys_lqr,'Eixo Real - Re')
            ylabel(grafico_sys_lqr,'Eixo Imaginário - Im');
            
            a1 = findobj(grafico_sys,'type','line')
            a2 = findobj(grafico_sys_lqr,'type','line')
            
            set(a1(3),'markersize',20)
            set(a1(3),'linewidth',2)
            set(a2(3), 'markersize',20)
            set(a2(3), 'linewidth',2)
            else
                 av1  = eig(A)
            app.av1.Text = num2str(av1(1))
            app.av2.Text = num2str(av1(2))
            app.av3.Text = num2str(av1(3))
            app.av4.Text = num2str(av1(4))
                 grafico_sys = subplot(1,1,1,'Parent',fig);
%             grafico_sys_lqr = subplot(1,2,2,'Parent',fig);          
            p1 = pzplot(grafico_sys,sys)           
%             p2 = pzplot(grafico_sys_lqr,sys_lqr)    
            grid(grafico_sys,"on")
%             grid(grafico_sys_lqr,"on")
            title(grafico_sys,'Polos de A')
%             title(grafico_sys_lqr,'Polos de A-BK')
            xlabel(grafico_sys,'Eixo Real - Re')
            ylabel(grafico_sys,'Eixo Imaginário - Im');
%             xlabel(grafico_sys_lqr,'Eixo Real - Re')
%             ylabel(grafico_sys_lqr,'Eixo Imaginário - Im');
            
            a1 = findobj(grafico_sys,'type','line')
%             a2 = findobj(grafico_sys_lqr,'type','line')
            
            set(a1(3),'markersize',20)
            set(a1(3),'linewidth',2)
%             set(a2(3), 'markersize',20)
%             set(a2(3), 'linewidth',2)
            
            end
        end

        % Button pushed function: btn_visualizar_ganho_kalman
        function btn_visualizar_ganho_kalmanButtonPushed(app, event)
                fig = uifigure
                tl  = tiledlayout(fig,2,2,'TileSpacing',"compact",'Padding',"compact");
                title(tl,'Simulação do Sistema Linearizado e Não-LinearGanho de Kalman - Forma Recursiva')
                xlabel(tl,'Tempo - s')
                ylabel(tl,'Ganho de Kalman - L')     
                y1 = [];
                y2 = [];
                x = [];
                
                %% Tempo de Simulação
                tempo_simulacao = app.tempo_simulacao.Value;
                
                %% Filtragem
                app.dropbox_filtragem.ItemsData = [1 2]
                filtrar = app.dropbox_filtragem.Value
%                 disp(filtrar)            
                if filtrar == 2 && app.radio_filtro_kalman.Value == 1
                  y1 = app.L_FK(:,1:2:end); 
                  y2 = app.L_FK(:,2:2:end);
                  x = linspace(0,tempo_simulacao,length(y1))
                elseif filtrar == 2 && app.radio_filtro_kalman_estendido.Value == 1                               
                  y1 = app.L_EKF(:,1:2:end); 
                  y2 = app.L_EKF(:,2:2:end);
                  x = linspace(0,tempo_simulacao,length(y1))
                end
                             
            grafico_l_x = nexttile(tl)
            p1_a = plot(grafico_l_x,x,y1(1,:)) 
            hold(grafico_l_x,'on');
            p1_b = plot(grafico_l_x,x,y2(1,:));
            grid(grafico_l_x,'on');
            title(grafico_l_x,'Parte referente à posição do carrinho')
            legend(grafico_l_x,'Saida 1 - (Posição do Carrinho)','Saida 2 - (Ângulo da Haste)')
            hold(grafico_l_x,'off');
             
            grafico_l_dotx = nexttile(tl)
            p2_a = plot(grafico_l_dotx,x,y1(3,:)) 
            hold(grafico_l_dotx,'on');
            p2_b = plot(grafico_l_dotx,x,y2(3,:));
            grid(grafico_l_dotx,'on');
            title(grafico_l_dotx,'Parte referente ao Ângulo da Haste')
            legend(grafico_l_dotx,'Saida 1 - (Posição do Carrinho)','Saida 2 - (Ângulo da Haste)')
            hold(grafico_l_dotx,'off');
            
            grafico_l_alpha = nexttile(tl)
            p3_a = plot(grafico_l_alpha,x,y1(2,:)) 
            hold(grafico_l_alpha,'on');
            p3_b = plot(grafico_l_alpha,x,y2(2,:));
            grid(grafico_l_alpha,'on');
            title(grafico_l_alpha,'Parte relacionada à Velocidade do Carrinho')
            legend(grafico_l_alpha,'Saida 1 - (Posição do Carrinho)','Saida 2 - (Ângulo da Haste)')
            hold(grafico_l_alpha,'off');
            
            grafico_l_dotalpha = nexttile(tl)
            p4_a = plot(grafico_l_dotalpha,x,y1(4,:)) 
            hold(grafico_l_dotalpha,'on');
            p4_b = plot(grafico_l_dotalpha,x,y2(4,:));
            grid(grafico_l_dotalpha,'on');
            title(grafico_l_dotalpha,'Parte relacionada à velocidade da haste')
            legend(grafico_l_dotalpha,'Saida 1 - (Posição do Carrinho)','Saida 2 - (Ângulo da Haste)')
            hold(grafico_l_dotalpha,'off');
                     
        end

        % Button pushed function: GerarRuidoButton
        function GerarRuidoButtonPushed(app, event)
            % char(160) - Espaço
            % char(40) - (
            % char(41) - )
            % char(963) - sigma
            % char(178) - ^2
            % char(58) - :
            
            multigrafico = app.checkbox_multiplos_pdf_w.Value; 
            mu = app.spinner_media_w.Value;
            app.drop_w.ItemsData = [1 2]
            drop = app.drop_w.Value
            valor = app.valor_w.Value;
            
            if drop == 1
                variancia = valor;
                sigma = sqrt(variancia);
            elseif drop == 2
                sigma = valor;
                variancia = sigma^2;
            end  
                        
            app.variancia_w.Text = num2str(variancia);
            app.desvio_w.Text = num2str(sigma);                                 
                                                         
            x_length = 400                                      % Tamanho do vetor
            x = linspace(mu - 4*sigma, mu + 4*sigma, x_length)   % Vetor de 4000 posições
            vetor_ruido_w =  mu + sigma * randn(length(x),1);          % Vetor de ruídos
            j = pdf('Normal',x,mu,sigma)                         % Distribuição normal de t com média mu e desvio padrão sigma
            y = pdf('Normal',mu,mu,sigma);                       % Valor da pdf em mu-sigma que é igual ao valor da pdf em mu+sigma  
            y1 = pdf('Normal',mu-sigma,mu,sigma);                % Valor da pdf em mu-sigma que é igual ao valor da pdf em mu+sigma  
            y2 = pdf('Normal',mu-2*sigma,mu,sigma);              % Valor da pdf em mu-sigma que é igual ao valor da pdf em mu+sigma  
            y3 = pdf('Normal',mu-3*sigma,mu,sigma);              % Valor da pdf em mu-sigma que é igual ao valor da pdf em mu+sigma  
            y4 = pdf('Normal',mu-4*sigma,mu,sigma);              % Valor da pdf em mu-sigma que é igual ao valor da pdf em mu+sigma  
            
            
            if multigrafico == 0;
              cla(app.grafico_fdp_w);            
            end
          
            hold(app.grafico_fdp_w,'on');
            tamanho = x_length/8;  % Tamanho do desvio padrão
            
            %% Cria os pontos no eixo x para os desvios padrões
            m1 = linspace(mu-sigma,mu+sigma,2*tamanho); 
            m2_e = linspace(mu-2*sigma,mu-sigma,tamanho);
            m2_d = linspace(mu+sigma,mu+2*sigma,tamanho);      
            m3_e = linspace(mu-3*sigma,mu-2*sigma,tamanho);  
            m3_d = linspace(mu+2*sigma,mu+3*sigma,tamanho);
            m4_e = linspace(mu-4*sigma,mu-3*sigma,tamanho); 
            m4_d = linspace(mu+3*sigma,mu+4*sigma,tamanho);
            
            %% Cria os pontos em y relacionadas aos eixo x através da pdf
            j1 = pdf('Normal',m1,mu,sigma);
            j2_e = pdf('Normal',m2_e,mu,sigma);
            j2_d = pdf('Normal',m2_d,mu,sigma);
            j3_e = pdf('Normal',m3_e,mu,sigma);
            j3_d = pdf('Normal',m3_d,mu,sigma);
            j4_e = pdf('Normal',m4_e,mu,sigma);
            j4_d = pdf('Normal',m4_d,mu,sigma);
            
            %% Cria as áreas a serem pintadas no gráfico da pdf
            a = zeros(4,1)
            a(4) = area(app.grafico_fdp_w,[m4_e m4_d],[j4_e j4_d]);
            a(3) = area(app.grafico_fdp_w,[m3_e m3_d],[j3_e j3_d]);
            a(2) = area(app.grafico_fdp_w,[m2_e m2_d],[j2_e j2_d]);
            a(1) = area(app.grafico_fdp_w,m1,j1);

            if multigrafico == 0
                
                legend(a, '68.26%','27.7%','4.003%','0.005%');                
                % % RGB copiado do site https://www.rapidtables.com/web/color/RGB_Color.html
                RGB = validatecolor({'#99CCFF','#66B2FF','#3399FF','#0080FF'},'multiple')
                set(a(1),'FaceColor',RGB(1,:));
                set(a(2),'FaceColor',RGB(2,:));
                set(a(3),'FaceColor',RGB(3,:));
                set(a(4),'FaceColor',RGB(4,:));
            
            
%%              Cria as linhas delimitadoras entre os desvios padrões
%               line(app.grafico_fdp_w,[mu mu],[0 y]);                         % Linha delimitadora do desvio padrão direita    
%               line(app.grafico_fdp_w,[mu+sigma mu+sigma],[0 y1]);              % Linha delimitadora do desvio padrão direita
%               line(app.grafico_fdp_w,[mu-sigma mu-sigma],[0 y1]);              % Linha delimitadora do desvio padrão esquerda
%               line(app.grafico_fdp_w,[mu-2*sigma mu-2*sigma],[0 y2]);         % Linha delimitadora do desvio padrão esquerda
%               line(app.grafico_fdp_w,[mu+2*sigma mu+2*sigma],[0 y2]);         % Linha delimitadora do desvio padrão direita
%               line(app.grafico_fdp_w,[mu-3*sigma mu-3*sigma],[0 y3]);         % Linha delimitadora do desvio padrão esquerda
%               line(app.grafico_fdp_w,[mu+3*sigma mu+3*sigma],[0 y3]);         % Linha delimitadora do desvio padrão direita
%               line(app.grafico_fdp_w,[mu-4*sigma mu-4*sigma],[0 y4]);         % Linha delimitadora do desvio padrão esquerda
%               line(app.grafico_fdp_w,[mu+4*sigma mu+4*sigma],[0 y4]);         % Linha delimitadora do desvio padrão direita

            
                app.grafico_fdp_w.XTick = [mu-4*sigma mu-3*sigma  mu-2*sigma mu-sigma mu mu+sigma mu+2*sigma  mu+3*sigma mu+4*sigma];
                app.grafico_fdp_w.XTickLabel = {'-4\sigma','-3\sigma','-2\sigma','-\sigma','\mu','\sigma','2\sigma','3\sigma','4\sigma'};
            end
            
            if multigrafico == 1                
                l = legend(app.grafico_ruido_w);                
                l.ButtonDownFcn = {@app.legenda,app.grafico_ruido_w};        
                hold(app.grafico_ruido_w,'on');
            end;
            
            x = 1:x_length
            plot(app.grafico_ruido_w,x,vetor_ruido_w);
            grid(app.grafico_ruido_w,'on');
            app.grafico_ruido_w.YLabel.String = 'Desvio Padrão - \sigma '
            app.grafico_ruido_w.XLabel.String = 'Amostras'
            
                        
            legenda = strcat(char(963),char(58),char(160),sprintf('%0.5f',sigma),char(160),char(160),char(160),char(963),char(178),char(58),char(160),sprintf('%0.5f',variancia));            
            ruido_w = [mu;sigma;variancia]            
            if isempty(app.ruidos_w) && multigrafico == 1
                  app.legendas_ruidos = {legenda};
                  legend(app.grafico_ruido_w,  app.legendas_ruidos);
                  app.ruidos_w = [ruido_w];
            elseif not(isempty(app.ruidos_w)) && multigrafico == 1
                  app.legendas_ruidos{end,end+1} = legenda;
                  legend(app.grafico_ruido_w, app.legendas_ruidos);
                  app.ruidos_w = [app.ruidos_w ruido_w];
            end
            
            %% Adaptando o eixo y para ter os limites de até
            %  4 vezes o maior desvio padrão para podermos 
            %  visualizar melho o gráfico e termos todos os ruídos dentro
            %  do gráfico apresentado.
            if multigrafico == 1
                maior_sigma = max(app.ruidos_w(2,:))
                app.grafico_ruido_w.YTick = [-4*maior_sigma -3*maior_sigma  -2*maior_sigma -maior_sigma mu maior_sigma 2*maior_sigma  3*maior_sigma 4*maior_sigma ];
                app.grafico_ruido_w.YTickLabel = {num2str(-4*maior_sigma), num2str(-3*maior_sigma),  num2str(-2*maior_sigma), num2str(-maior_sigma), num2str(mu), num2str(maior_sigma), num2str(2*maior_sigma),  num2str(3*maior_sigma), num2str(4*maior_sigma)};
                app.grafico_ruido_w.YLim = [-4*maior_sigma 4*maior_sigma]
            else    
                app.grafico_ruido_w.YTick = [-4*sigma -3*sigma  -2*sigma -sigma mu sigma 2*sigma  3*sigma 4*sigma ];
                app.grafico_ruido_w.YTickLabel = {num2str(-4*sigma), num2str(-3*sigma),  num2str(-2*sigma), num2str(-sigma), num2str(mu), num2str(sigma), num2str(2*sigma),  num2str(3*sigma), num2str(4*sigma)};
                app.grafico_ruido_w.YLim = [-4*sigma 4*sigma]
            end

        end

        % Button pushed function: GerarRuidoButton_v
        function GerarRuidoButton_vPushed(app, event)
             % char(160) - Espaço
            % char(40) - (
            % char(41) - )
            % char(963) - sigma
            % char(178) - ^2
            % char(58) - :
            
            multigrafico = app.checkbox_multiplos_pdf_v.Value; 
            mu = app.spinner_media_v.Value;
            app.drop_v.ItemsData = [1 2]
            drop = app.drop_v.Value
            valor = app.valor_v.Value;
            
            if drop == 1
                variancia = valor;
                sigma = sqrt(variancia);
            elseif drop == 2
                sigma = valor;
                variancia = sigma^2;
            end  
                        
            app.variancia_v.Text = num2str(variancia);
            app.desvio_v.Text = num2str(sigma);                                 
                                                         
            x_length = 400                                      % Tamanho do vetor
            x = linspace(mu - 4*sigma, mu + 4*sigma, x_length)   % Vetor de 4000 posições
            vetor_ruido_v =  mu + sigma * randn(length(x),1);          % Vetor de ruídos
            j = pdf('Normal',x,mu,sigma)                         % Distribuição normal de t com média mu e desvio padrão sigma
            y = pdf('Normal',mu,mu,sigma);                       % Valor da pdf em mu-sigma que é igual ao valor da pdf em mu+sigma  
            y1 = pdf('Normal',mu-sigma,mu,sigma);                % Valor da pdf em mu-sigma que é igual ao valor da pdf em mu+sigma  
            y2 = pdf('Normal',mu-2*sigma,mu,sigma);              % Valor da pdf em mu-sigma que é igual ao valor da pdf em mu+sigma  
            y3 = pdf('Normal',mu-3*sigma,mu,sigma);              % Valor da pdf em mu-sigma que é igual ao valor da pdf em mu+sigma  
            y4 = pdf('Normal',mu-4*sigma,mu,sigma);              % Valor da pdf em mu-sigma que é igual ao valor da pdf em mu+sigma  
            
            
            if multigrafico == 0;
              cla(app.grafico_fdp_v);            
            end
          
            hold(app.grafico_fdp_v,'on');
            tamanho = x_length/8;  % Tamanho do desvio padrão
            
            %% Cria os pontos no eixo x para os desvios padrões
            m1 = linspace(mu-sigma,mu+sigma,2*tamanho); 
            m2_e = linspace(mu-2*sigma,mu-sigma,tamanho);
            m2_d = linspace(mu+sigma,mu+2*sigma,tamanho);      
            m3_e = linspace(mu-3*sigma,mu-2*sigma,tamanho);  
            m3_d = linspace(mu+2*sigma,mu+3*sigma,tamanho);
            m4_e = linspace(mu-4*sigma,mu-3*sigma,tamanho); 
            m4_d = linspace(mu+3*sigma,mu+4*sigma,tamanho);
            
            %% Cria os pontos em y relacionadas aos eixo x através da pdf
            j1 = pdf('Normal',m1,mu,sigma);
            j2_e = pdf('Normal',m2_e,mu,sigma);
            j2_d = pdf('Normal',m2_d,mu,sigma);
            j3_e = pdf('Normal',m3_e,mu,sigma);
            j3_d = pdf('Normal',m3_d,mu,sigma);
            j4_e = pdf('Normal',m4_e,mu,sigma);
            j4_d = pdf('Normal',m4_d,mu,sigma);
            
            %% Cria as áreas a serem pintadas no gráfico da pdf
            a = zeros(4,1)
            a(4) = area(app.grafico_fdp_v,[m4_e m4_d],[j4_e j4_d]);
            a(3) = area(app.grafico_fdp_v,[m3_e m3_d],[j3_e j3_d]);
            a(2) = area(app.grafico_fdp_v,[m2_e m2_d],[j2_e j2_d]);
            a(1) = area(app.grafico_fdp_v,m1,j1);

            if multigrafico == 0
                
                legend(a, '68.26%','27.7%','4.003%','0.005%');                
                % % RGB copiado do site https://www.rapidtables.com/web/color/RGB_Color.html
                RGB = validatecolor({'#99CCFF','#66B2FF','#3399FF','#0080FF'},'multiple')
                set(a(1),'FaceColor',RGB(1,:));
                set(a(2),'FaceColor',RGB(2,:));
                set(a(3),'FaceColor',RGB(3,:));
                set(a(4),'FaceColor',RGB(4,:));
            
            
%%              Cria as linhas delimitadoras entre os desvios padrões
%               line(app.grafico_fdp_w,[mu mu],[0 y]);                         % Linha delimitadora do desvio padrão direita    
%               line(app.grafico_fdp_w,[mu+sigma mu+sigma],[0 y1]);              % Linha delimitadora do desvio padrão direita
%               line(app.grafico_fdp_w,[mu-sigma mu-sigma],[0 y1]);              % Linha delimitadora do desvio padrão esquerda
%               line(app.grafico_fdp_w,[mu-2*sigma mu-2*sigma],[0 y2]);         % Linha delimitadora do desvio padrão esquerda
%               line(app.grafico_fdp_w,[mu+2*sigma mu+2*sigma],[0 y2]);         % Linha delimitadora do desvio padrão direita
%               line(app.grafico_fdp_w,[mu-3*sigma mu-3*sigma],[0 y3]);         % Linha delimitadora do desvio padrão esquerda
%               line(app.grafico_fdp_w,[mu+3*sigma mu+3*sigma],[0 y3]);         % Linha delimitadora do desvio padrão direita
%               line(app.grafico_fdp_w,[mu-4*sigma mu-4*sigma],[0 y4]);         % Linha delimitadora do desvio padrão esquerda
%               line(app.grafico_fdp_w,[mu+4*sigma mu+4*sigma],[0 y4]);         % Linha delimitadora do desvio padrão direita

            
                app.grafico_fdp_v.XTick = [mu-4*sigma mu-3*sigma  mu-2*sigma mu-sigma mu mu+sigma mu+2*sigma  mu+3*sigma mu+4*sigma];
                app.grafico_fdp_v.XTickLabel = {'-4\sigma','-3\sigma','-2\sigma','-\sigma','\mu','\sigma','2\sigma','3\sigma','4\sigma'};
            end
            
            if multigrafico == 1                
                l = legend(app.grafico_ruido_v);                
                l.ButtonDownFcn = {@app.legenda,app.grafico_ruido_v};        
                hold(app.grafico_ruido_v,'on');
            end;
            
            x = 1:x_length
            plot(app.grafico_ruido_v,x,vetor_ruido_v);
            grid(app.grafico_ruido_v,'on');
            app.grafico_ruido_v.YLabel.String = 'Desvio Padrão - \sigma '
            app.grafico_ruido_v.XLabel.String = 'Amostras'
            
                        
            legenda = strcat(char(963),char(58),char(160),sprintf('%0.5f',sigma),char(160),char(160),char(160),char(963),char(178),char(58),char(160),sprintf('%0.5f',variancia));            
            ruido_v = [mu;sigma;variancia]            
            if isempty(app.ruidos_v) && multigrafico == 1
                  app.legendas_ruidos = {legenda};
                  legend(app.grafico_ruido_v,  app.legendas_ruidos);
                  app.ruidos_v = [ruido_v];
            elseif not(isempty(app.ruidos_v)) && multigrafico == 1
                  app.legendas_ruidos{end,end+1} = legenda;
                  legend(app.grafico_ruido_v, app.legendas_ruidos);
                  app.ruidos_v = [app.ruidos_v ruido_v];
            end
            
            %% Adaptando o eixo y para ter os limites de até
            %  4 vezes o maior desvio padrão para podermos 
            %  visualizar melho o gráfico e termos todos os ruídos dentro
            %  do gráfico apresentado.
            if multigrafico == 1
                maior_sigma = max(app.ruidos_v(2,:))
                app.grafico_ruido_v.YTick = [-4*maior_sigma -3*maior_sigma  -2*maior_sigma -maior_sigma mu maior_sigma 2*maior_sigma  3*maior_sigma 4*maior_sigma ];
                app.grafico_ruido_v.YTickLabel = {num2str(-4*maior_sigma), num2str(-3*maior_sigma),  num2str(-2*maior_sigma), num2str(-maior_sigma), num2str(mu), num2str(maior_sigma), num2str(2*maior_sigma),  num2str(3*maior_sigma), num2str(4*maior_sigma)};
                app.grafico_ruido_v.YLim = [-4*maior_sigma 4*maior_sigma]
            else    
                app.grafico_ruido_v.YTick = [-4*sigma -3*sigma  -2*sigma -sigma mu sigma 2*sigma  3*sigma 4*sigma ];
                app.grafico_ruido_v.YTickLabel = {num2str(-4*sigma), num2str(-3*sigma),  num2str(-2*sigma), num2str(-sigma), num2str(mu), num2str(sigma), num2str(2*sigma),  num2str(3*sigma), num2str(4*sigma)};
                app.grafico_ruido_v.YLim = [-4*sigma 4*sigma]
            end
        end

        % Value changed function: checkbox_multiplos_pdf_w
        function checkbox_multiplos_pdf_wValueChanged(app, event)

            value = app.checkbox_multiplos_pdf_w.Value;
              if value == 1
                  set(app.checkbox_utilizar_simulacao_w,'Enable','off')
              else
                  set(app.checkbox_utilizar_simulacao_w,'Enable','on')
              end
              cla(app.grafico_fdp_w,'reset');     
              app.configura_grafico(app.grafico_fdp_w,'Função Densidade de Probabilidades - w','Variável Aleatória','Função','on','on','on',[313,351,473,292])
              
              cla(app.grafico_ruido_w,'reset');
              app.configura_grafico(app.grafico_ruido_w,'Ruído - w','Ocorrências','Desvio Padrão - \sigma','on','on','on',[313,122,473,225])

        end

        % Value changed function: checkbox_utilizar_simulacao_w
        function checkbox_utilizar_simulacao_wValueChanged(app, event)
           
            value = app.checkbox_utilizar_simulacao_w.Value;
            
            if value == 1
                mu = app.spinner_media_w.Value;
                app.drop_w.ItemsData = [1 2]
                drop = app.drop_w.Value
                valor = app.valor_w.Value;
                if drop == 1
                   variancia = valor;
                   sigma = sqrt(variancia);
                elseif drop == 2
                   sigma = valor;
                   variancia = sigma^2;
                end  
                app.simulacao_media_w.Value = mu;
                app.simulacao_variancia_w.Value = variancia;
          
            end
            
        end

        % Value changed function: checkbox_multiplos_pdf_v
        function checkbox_multiplos_pdf_vValueChanged(app, event)
     
            value = app.checkbox_multiplos_pdf_v.Value;
              if value == 1
                  set(app.checkbox_utilizar_simulacao_v,'Enable','off')
              else
                  set(app.checkbox_utilizar_simulacao_v,'Enable','on')
              end
              
              cla(app.grafico_fdp_v,'reset');     
              app.configura_grafico(app.grafico_fdp_v,'Função Densidade de Probabilidades - v','Variável Aleatória','Função','on','on','on',[806,351,473,292])
              
              cla(app.grafico_ruido_v,'reset');
              app.configura_grafico(app.grafico_ruido_v,'Ruído - v','Ocorrências','Desvio Padrão - \sigma','on','on','on',[806,122,473,225])

        end

        % Value changed function: checkbox_utilizar_simulacao_v
        function checkbox_utilizar_simulacao_vValueChanged(app, event)
           
            value = app.checkbox_utilizar_simulacao_v.Value;
            if value == 1
                mu = app.spinner_media_v.Value;
                app.drop_v.ItemsData = [1 2]
                drop = app.drop_v.Value
                valor = app.valor_v.Value;
                if drop == 1
                   variancia = valor;
                   sigma = sqrt(variancia);
                elseif drop == 2
                   sigma = valor;
                   variancia = sigma^2;
                end  
                app.simulacao_media_v.Value = mu;
                app.simulacao_variancia_v.Value = variancia;
          
            end
        end

        % Value changed function: checkbox_linearizado
        function checkbox_linearizadoValueChanged(app, event)
            
            linearizado = app.checkbox_linearizado.Value;
            nao_linear = app.checkbox_nao_linear.Value;
            
            if linearizado == 1 && nao_linear == 1
                set(app.simular,'Enable','on')
            elseif linearizado == 0 && nao_linear == 0
                set(app.simular,'Enable','off')
            elseif linearizado == 1 || nao_linear == 1            
                set(app.simular,'Enable','on')
            end    
        end

        % Value changed function: checkbox_nao_linear
        function checkbox_nao_linearValueChanged(app, event)
           linearizado = app.checkbox_linearizado.Value;
            nao_linear = app.checkbox_nao_linear.Value;
            
            if linearizado == 1 && nao_linear == 1
                set(app.simular,'Enable','on')
            elseif linearizado == 0 && nao_linear == 0
                set(app.simular,'Enable','off')
            elseif linearizado == 1 || nao_linear == 1            
                set(app.simular,'Enable','on')
            end  
            
        end

        % Button pushed function: button_animacao
        function button_animacaoButtonPushed(app, event)
            %% Modificando o tamanho da Haste            
            app.l = 2
            
            %% Criando a figura da animação
            fig = uifigure;
            
            %% Filtragem
            app.dropbox_filtragem.ItemsData = [1 2]
            filtrar = app.dropbox_filtragem.Value
            
            %% Filtros
            KF = app.radio_filtro_kalman.Value;
            EKF = app.radio_filtro_kalman_estendido.Value;            
             
            %% Inclusão do controle LQR
            incluir_controle_lqr = app.checkbox_incluir_controle.Value;
            
            %% Linearização
            linearizado = app.checkbox_linearizado.Value;
            nao_linear = app.checkbox_nao_linear.Value;
            
             if filtrar == 1             
                if linearizado == 1 && nao_linear == 1
                     tl= tiledlayout(fig,2,2,'TileSpacing',"compact",'Padding',"compact");
                     title(tl,'Simulação do Sistema Linearizado e Não-Linear')
                     xlabel(tl,'Posição do Carrinho')
                     ylabel(tl,'l*cos(\alpha)')
                     
                     y = app.Y;
                     x = app.X;
                     y_nl = app.Y_nl;
                     x_nl = app.X_nl;
                     
                     grafico_animacao_real_sem_ruido = nexttile(tl)
                     p1_a = plot(grafico_animacao_real_sem_ruido,[x(1,1), x(1,1)+app.l*sin(x(3,1))],[0, app.l*cos(x(3,1))],'Color','blue','LineWidth',2) 
                     hold(grafico_animacao_real_sem_ruido,'on');
                     p1_b = plot(grafico_animacao_real_sem_ruido,x(1,1)+[-.5,.5,.5,-.5,-.5], [0,0,-.25,-.25,0],'magenta','LineWidth',2);
                     axis(grafico_animacao_real_sem_ruido,[-4,4,-3,3]);
                     grid(grafico_animacao_real_sem_ruido,'on');
                     title(grafico_animacao_real_sem_ruido,'Sistema Real Linearizado sem Ruído')
                     hold(grafico_animacao_real_sem_ruido,'off');
                     
                     grafico_animacao_real_com_ruido = nexttile(tl)
                     p2_a = plot(grafico_animacao_real_com_ruido,[y(1,1), y(1,1)+app.l*sin(y(2,1))],[0, app.l*cos(y(2,1))],'Color','blue','LineWidth',2) 
                     hold(grafico_animacao_real_com_ruido,'on');
                     p2_b = plot(grafico_animacao_real_com_ruido,y(1,1)+[-.5,.5,.5,-.5,-.5], [0,0,-.25,-.25,0],'magenta','LineWidth',2);
                     axis(grafico_animacao_real_com_ruido,[-4,4,-3,3]);
                     grid(grafico_animacao_real_com_ruido,'on');
                     title(grafico_animacao_real_com_ruido,'Medição do Sistema Real Linearizado')
                     hold(grafico_animacao_real_com_ruido,'off');
                     
                     grafico_animacao_estimada_sem_ruido = nexttile(tl)
                     p3_a = plot(grafico_animacao_estimada_sem_ruido,[x_nl(1,1), x_nl(1,1)+app.l*sin(x_nl(3,1))], [0, app.l*cos(x_nl(3,1))],'Color','blue','LineWidth',2)
                     hold(grafico_animacao_estimada_sem_ruido,'on');
                     p3_b = plot(grafico_animacao_estimada_sem_ruido,x_nl(1,1)+[-.5,.5,.5,-.5,-.5], [0,0,-.25,-.25,0],'magenta','LineWidth',2);
                     axis(grafico_animacao_estimada_sem_ruido,[-4,4,-3,3]);
                     grid(grafico_animacao_estimada_sem_ruido,'on');
                     title(grafico_animacao_estimada_sem_ruido,'Sistema Real Não-Linear sem Ruído')
                     hold(grafico_animacao_estimada_sem_ruido,'on');
                     
                     grafico_animacao_estimada_com_ruido = nexttile(tl)
                     p4_a = plot(grafico_animacao_estimada_com_ruido,[y_nl(1,1), y_nl(1,1)+app.l*sin(y_nl(2,1))], [0, app.l*cos(y_nl(2,1))],'blue','LineWidth',2)
                     hold(grafico_animacao_estimada_com_ruido,'on');
                     p4_b = plot(grafico_animacao_estimada_com_ruido,y_nl(1,1)+[-.5,.5,.5,-.5,-.5], [0,0,-.25,-.25,0],'magenta','LineWidth',2);
                     axis(grafico_animacao_estimada_com_ruido,[-4,4,-3,3]);
                     grid(grafico_animacao_estimada_com_ruido,'on');
                     title(grafico_animacao_estimada_com_ruido,'Medição do Sistema Real Não-Linear')
                     hold(grafico_animacao_estimada_com_ruido,'off');
                     
                     for i = 2:length(x)    
                        if ~(ishandle(fig) && strcmp(get(fig, 'type'), 'figure'))
                            break;
                        end
                         
                        set(p1_a,'XData',[x(1,i), x(1,i)+app.l*sin(x(3,i))], 'YData',[0, app.l*cos(x(3,i))]);
                        set(p2_a,'XData',[y(1,i), y(1,i)+app.l*sin(y(2,i))], 'YData',[0, app.l*cos(y(2,i))]); 
                        set(p3_a,'XData',[x_nl(1,i), x_nl(1,i)+app.l*sin(x_nl(3,i))], 'YData',[0, app.l*cos(x_nl(3,i))]);
                        set(p4_a,'XData',[y_nl(1,i), y_nl(1,i)+app.l*sin(y_nl(2,i))], 'YData',[0, app.l*cos(y_nl(2,i))]); 
   
                        set(p1_b,'XData',x(1,i)+[-.5,.5,.5,-.5,-.5], 'YData',[0,0,-.25,-.25,0]);
                        set(p2_b,'XData',y(1,i)+[-.5,.5,.5,-.5,-.5], 'YData',[0,0,-.25,-.25,0]); 
                        set(p3_b,'XData',x_nl(1,i)+[-.5,.5,.5,-.5,-.5], 'YData',[0,0,-.25,-.25,0]);
                        set(p4_b,'XData',y_nl(1,i)+[-.5,.5,.5,-.5,-.5], 'YData',[0,0,-.25,-.25,0]); 

                        drawnow;

                    end
                    
                elseif linearizado == 1 || nao_linear == 1
                    
                    tl= tiledlayout(fig,1,2,'TileSpacing',"compact",'Padding',"compact");
                    title(tl,'Simulação do Sistema com e sem Ruídos')
                    xlabel(tl,'Posição do Carrinho')
                    ylabel(tl,'l*cos(\alpha)')
                    
                    y = app.Y;
                    x = app.X;
                    
                    grafico_animacao_real_sem_ruido = nexttile(tl)
                    p1_a = plot(grafico_animacao_real_sem_ruido,[x(1,1), x(1,1)+app.l*sin(x(3,1))],[0, app.l*cos(x(3,1))],'Color','blue','LineWidth',2) 
                    hold(grafico_animacao_real_sem_ruido,'on');
                    p1_b = plot(grafico_animacao_real_sem_ruido,x(1,1)+[-.5,.5,.5,-.5,-.5], [0,0,-.25,-.25,0],'magenta','LineWidth',2);
                    axis(grafico_animacao_real_sem_ruido,[-4,4,-3,3]);
                    grid(grafico_animacao_real_sem_ruido,'on');
                    title(grafico_animacao_real_sem_ruido,'Sistema Real')
                    hold(grafico_animacao_real_sem_ruido,'off');
                     
                    grafico_animacao_real_com_ruido = nexttile(tl)
                    p2_a = plot(grafico_animacao_real_com_ruido,[y(1,1), y(1,1)+app.l*sin(y(2,1))],[0, app.l*cos(y(2,1))],'Color','blue','LineWidth',2) 
                    hold(grafico_animacao_real_com_ruido,'on');
                    p2_b = plot(grafico_animacao_real_com_ruido,y(1,1)+[-.5,.5,.5,-.5,-.5], [0,0,-.25,-.25,0],'magenta','LineWidth',2);
                    axis(grafico_animacao_real_com_ruido,[-4,4,-3,3]);
                    grid(grafico_animacao_real_com_ruido,'on');
                    title(grafico_animacao_real_com_ruido,'Medição do Sistema Real')
                    hold(grafico_animacao_real_com_ruido,'off');
                    
                    
                    for i = 2:length(x)  
                        
                        %% Se a figura tiver sido apagada da memória
                        if ~(ishandle(fig) && strcmp(get(fig, 'type'), 'figure'))
                            break;
                        end                                     
                        set(p1_a,'XData',[x(1,i), x(1,i)+app.l*sin(x(3,i))], 'YData',[0, app.l*cos(x(3,i))]);
                        set(p2_a,'XData',[y(1,i), y(1,i)+app.l*sin(y(2,i))], 'YData',[0, app.l*cos(y(2,i))]); 
                       
                        set(p1_b,'XData',x(1,i)+[-.5,.5,.5,-.5,-.5], 'YData',[0,0,-.25,-.25,0]);
                        set(p2_b,'XData',y(1,i)+[-.5,.5,.5,-.5,-.5], 'YData',[0,0,-.25,-.25,0]); 
                        drawnow;
                    end
                end
             elseif filtrar == 2
                     tl= tiledlayout(fig,2,2,'TileSpacing',"compact",'Padding',"compact");
                     title(tl,'Simulação do Sistema Real e Estimado')
                     xlabel(tl,'Posição do Carrinho')
                     ylabel(tl,'l*cos(\alpha)')
                     
                     y = app.Y;
                     x = app.X;
                     xhat = app.Xhat;
                     yhat = app.Yhat;
                     
                     grafico_animacao_real_sem_ruido = nexttile(tl)
                     p1_a = plot(grafico_animacao_real_sem_ruido,[x(1,1), x(1,1)+app.l*sin(x(3,1))],[0, app.l*cos(x(3,1))],'Color','blue','LineWidth',2) 
                     hold(grafico_animacao_real_sem_ruido,'on');
                     p1_b = plot(grafico_animacao_real_sem_ruido,x(1,1)+[-.5,.5,.5,-.5,-.5], [0,0,-.25,-.25,0],'magenta','LineWidth',2);
                     axis(grafico_animacao_real_sem_ruido,[-4,4,-3,3]);
                     grid(grafico_animacao_real_sem_ruido,'on');
                     title(grafico_animacao_real_sem_ruido,'Sistema Real Linearizado sem Ruído')
                     hold(grafico_animacao_real_sem_ruido,'off');
                     
                     grafico_animacao_real_com_ruido = nexttile(tl)
                     p2_a = plot(grafico_animacao_real_com_ruido,[y(1,1), y(1,1)+app.l*sin(y(2,1))],[0, app.l*cos(y(2,1))],'Color','blue','LineWidth',2) 
                     hold(grafico_animacao_real_com_ruido,'on');
                     p2_b = plot(grafico_animacao_real_com_ruido,y(1,1)+[-.5,.5,.5,-.5,-.5], [0,0,-.25,-.25,0],'magenta','LineWidth',2);
                     axis(grafico_animacao_real_com_ruido,[-4,4,-3,3]);
                     grid(grafico_animacao_real_com_ruido,'on');
                     title(grafico_animacao_real_com_ruido,'Medição do Sistema Real Linearizado')
                     hold(grafico_animacao_real_com_ruido,'off');
                     
                     grafico_animacao_estimada_sem_ruido = nexttile(tl)
                     p3_a = plot(grafico_animacao_estimada_sem_ruido,[xhat(1,1), xhat(1,1)+app.l*sin(xhat(3,1))], [0, app.l*cos(xhat(3,1))],'Color','blue','LineWidth',2)
                     hold(grafico_animacao_estimada_sem_ruido,'on');
                     p3_b = plot(grafico_animacao_estimada_sem_ruido,xhat(1,1)+[-.5,.5,.5,-.5,-.5], [0,0,-.25,-.25,0],'magenta','LineWidth',2);
                     axis(grafico_animacao_estimada_sem_ruido,[-4,4,-3,3]);
                     grid(grafico_animacao_estimada_sem_ruido,'on');
                     title(grafico_animacao_estimada_sem_ruido,'Sistema Real Não-Linear sem Ruído')
                     hold(grafico_animacao_estimada_sem_ruido,'on');
                     
                     grafico_animacao_estimada_com_ruido = nexttile(tl)
                     p4_a = plot(grafico_animacao_estimada_com_ruido,[yhat(1,1), yhat(1,1)+app.l*sin(yhat(2,1))], [0, app.l*cos(yhat(2,1))],'blue','LineWidth',2)
                     hold(grafico_animacao_estimada_com_ruido,'on');
                     p4_b = plot(grafico_animacao_estimada_com_ruido,yhat(1,1)+[-.5,.5,.5,-.5,-.5], [0,0,-.25,-.25,0],'magenta','LineWidth',2);
                     axis(grafico_animacao_estimada_com_ruido,[-4,4,-3,3]);
                     grid(grafico_animacao_estimada_com_ruido,'on');
                     title(grafico_animacao_estimada_com_ruido,'Medição do Sistema Real Não-Linear')
                     hold(grafico_animacao_estimada_com_ruido,'off');                     
                    
                     for i = 1:length(x) 
                         
                        if ~(ishandle(fig) && strcmp(get(fig, 'type'), 'figure'))
                            break;
                        end 
                         
                        set(p1_a,'XData',[x(1,i), x(1,i)+app.l*sin(x(3,i))], 'YData',[0, app.l*cos(x(3,i))]);
                        set(p2_a,'XData',[y(1,i), y(1,i)+app.l*sin(y(2,i))], 'YData',[0, app.l*cos(y(2,i))]); 
                        set(p3_a,'XData',[xhat(1,i), xhat(1,i)+app.l*sin(xhat(3,i))], 'YData',[0, app.l*cos(xhat(3,i))]);
                        set(p4_a,'XData',[yhat(1,i), yhat(1,i)+app.l*sin(yhat(2,i))], 'YData',[0, app.l*cos(yhat(2,i))]); 
   
                        set(p1_b,'XData',x(1,i)+[-.5,.5,.5,-.5,-.5], 'YData',[0,0,-.25,-.25,0]);
                        set(p2_b,'XData',y(1,i)+[-.5,.5,.5,-.5,-.5], 'YData',[0,0,-.25,-.25,0]); 
                        set(p3_b,'XData',xhat(1,i)+[-.5,.5,.5,-.5,-.5], 'YData',[0,0,-.25,-.25,0]);
                        set(p4_b,'XData',yhat(1,i)+[-.5,.5,.5,-.5,-.5], 'YData',[0,0,-.25,-.25,0]); 

                        drawnow;
                    end
             end
            
            app.terminate_loop = 0;
            delete(fig);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SimulaoTab = uitab(app.TabGroup);
            app.SimulaoTab.Title = 'Simulação';
            app.grafico_covariancia = uiaxes(app.SimulaoTab);
            title(app.grafico_covariancia, 'Covariância do erro')
            xlabel(app.grafico_covariancia, 'Tempo - s')
            ylabel(app.grafico_covariancia, 'Carrinho (m/s) - Pêndulo (rad/s)')
            app.grafico_covariancia.Visible = 'off';
            app.grafico_posicao_carrinho = uiaxes(app.SimulaoTab);
            title(app.grafico_posicao_carrinho, 'Posição X  Tempo (Carrinho)')
            xlabel(app.grafico_posicao_carrinho, 'Tempo - s')
            ylabel(app.grafico_posicao_carrinho, 'Posição - m')
            zlabel(app.grafico_posicao_carrinho, 'Z')
            app.grafico_angulo_haste = uiaxes(app.SimulaoTab);
            title(app.grafico_angulo_haste, 'Ângulo x Tempo (Haste)')
            xlabel(app.grafico_angulo_haste, 'Tempo - s')
            ylabel(app.grafico_angulo_haste, 'Ângulo da Haste - º')
            app.grafico_erro_estimacao = uiaxes(app.SimulaoTab);
            title(app.grafico_erro_estimacao, 'Erro de estimação')
            xlabel(app.grafico_erro_estimacao, 'Tempo - s')
            ylabel(app.grafico_erro_estimacao, 'Carrinho (m/s) - Pêndulo (rad/s)')
            app.grafico_erro_estimacao.Visible = 'off';
            app.grafico_sinal_controle = uiaxes(app.SimulaoTab);
            title(app.grafico_sinal_controle, 'Sinal de Controle')
            xlabel(app.grafico_sinal_controle, 'Tempo')
            ylabel(app.grafico_sinal_controle, 'Esforço')
            app.grafico_velocidades = uiaxes(app.SimulaoTab);
            title(app.grafico_velocidades, 'Velocidades')
            xlabel(app.grafico_velocidades, 'Tempo - s')
            ylabel(app.grafico_velocidades, 'Carrinho (m/s) - Pêndulo (rad/s)')
            app.button_animacao = uibutton(app.SimulaoTab, 'push');
            app.button_animacao.ButtonPushedFcn = createCallbackFcn(app, @button_animacaoButtonPushed, true);
            app.button_animacao.Enable = 'off';
            app.button_animacao.Text = 'Animar';
            app.AutovaloresdeAPanel = uipanel(app.SimulaoTab);
            app.AutovaloresdeAPanel.Title = 'Auto-valores de A';
            app.GridLayout2 = uigridlayout(app.AutovaloresdeAPanel);
            app.av1 = uilabel(app.GridLayout2);
            app.av1.Text = '';
            app.av2 = uilabel(app.GridLayout2);
            app.av2.Text = '';
            app.av3 = uilabel(app.GridLayout2);
            app.av3.Text = '';
            app.av4 = uilabel(app.GridLayout2);
            app.av4.Text = '';
            app.panel_malha_fechada = uipanel(app.SimulaoTab);
            app.panel_malha_fechada.Title = 'Auto-valores de A-BK';
            app.GridLayout = uigridlayout(app.panel_malha_fechada);
            app.av11 = uilabel(app.GridLayout);
            app.av11.Text = '';
            app.av22 = uilabel(app.GridLayout);
            app.av22.Text = '';
            app.av33 = uilabel(app.GridLayout);
            app.av33.Text = '';
            app.av44 = uilabel(app.GridLayout);
            app.av44.Text = '';
            app.simular = uibutton(app.SimulaoTab, 'push');
            app.simular.ButtonPushedFcn = createCallbackFcn(app, @simularButtonPushed, true);
            app.simular.Text = 'Simular';
            app.RudonosensorwPanel = uipanel(app.SimulaoTab);
            app.RudonosensorwPanel.Title = 'Ruído no sensor - w';
            app.simulacao_media_label_v = uilabel(app.RudonosensorwPanel);
            app.simulacao_media_label_v.Enable = 'off';
            app.simulacao_media_label_v.Text = 'Edit Field';
            app.simulacao_media_v = uispinner(app.RudonosensorwPanel);
            app.simulacao_media_v.Limits = [0 100];
            app.simulacao_media_v.ValueDisplayFormat = '%.0f';
            app.simulacao_media_v.Enable = 'off';
            app.incluir_ruido_sensor = uicheckbox(app.RudonosensorwPanel);
            app.incluir_ruido_sensor.ValueChangedFcn = createCallbackFcn(app, @incluir_ruido_sensorValueChanged, true);
            app.incluir_ruido_sensor.Text = 'Incluir Ruído no Sensor - v';
            app.simulacao_variancia_v = uieditfield(app.RudonosensorwPanel, 'numeric');
            app.simulacao_variancia_v.ValueDisplayFormat = '%0.5f';
            app.simulacao_variancia_v.Enable = 'off';
            app.simulacao_variancia_v.Tooltip = {'Coloque aqui o valor da variância de v.'};
            app.simulacao_variancia_v.Value = 0.001;
            app.simulacao_variancia_label_v = uilabel(app.RudonosensorwPanel);
            app.simulacao_variancia_label_v.Enable = 'off';
            app.simulacao_variancia_label_v.Text = 'Edit Field';
            app.RudonosistemawPanel = uipanel(app.SimulaoTab);
            app.RudonosistemawPanel.Title = 'Ruído no sistema - w';
            app.simulacao_media_label_w = uilabel(app.RudonosistemawPanel);
            app.simulacao_media_label_w.Enable = 'off';
            app.simulacao_media_label_w.Text = 'Edit Field';
            app.simulacao_media_w = uispinner(app.RudonosistemawPanel);
            app.simulacao_media_w.Limits = [0 100];
            app.simulacao_media_w.ValueDisplayFormat = '%.0f';
            app.simulacao_media_w.Enable = 'off';
            app.simulacao_variancia_label_w = uilabel(app.RudonosistemawPanel);
            app.simulacao_variancia_label_w.Enable = 'off';
            app.simulacao_variancia_label_w.Text = 'Edit Field';
            app.simulacao_variancia_w = uieditfield(app.RudonosistemawPanel, 'numeric');
            app.simulacao_variancia_w.ValueDisplayFormat = '%0.5f';
            app.simulacao_variancia_w.Enable = 'off';
            app.simulacao_variancia_w.Tooltip = {'Coloque aqui o valor da variância de w.'};
            app.simulacao_variancia_w.Value = 0.005;
            app.incluir_ruido_sistema = uicheckbox(app.RudonosistemawPanel);
            app.incluir_ruido_sistema.ValueChangedFcn = createCallbackFcn(app, @incluir_ruido_sistemaValueChanged, true);
            app.incluir_ruido_sistema.Text = 'Incluir Ruído no Sistema - w';
            app.panel_ganho_k = uipanel(app.SimulaoTab);
            app.panel_ganho_k.Title = 'Ganho K';
            app.GridLayout3 = uigridlayout(app.panel_ganho_k);
            app.k1 = uilabel(app.GridLayout3);
            app.k1.Text = '';
            app.k2 = uilabel(app.GridLayout3);
            app.k2.Text = '';
            app.k3 = uilabel(app.GridLayout3);
            app.k3.Text = '';
            app.k4 = uilabel(app.GridLayout3);
            app.k4.Text = '';
            app.RSpinnerLabel = uilabel(app.SimulaoTab);
            app.RSpinnerLabel.Text = 'R';
            app.r = uispinner(app.SimulaoTab);
            app.r.Limits = [1 100];
            app.r.Value = 1;
            app.panel_matriz_q = uipanel(app.SimulaoTab);
            app.panel_matriz_q.Title = 'Matriz Q (Diagonal Principal)';
            app.VelocidadedaHasteEditFieldLabel = uilabel(app.panel_matriz_q);
            app.VelocidadedaHasteEditFieldLabel.Text = 'Velocidade da Haste';
            app.dotalpha = uieditfield(app.panel_matriz_q, 'numeric');
            app.PosiodoCarrinhoEditFieldLabel = uilabel(app.panel_matriz_q);
            app.PosiodoCarrinhoEditFieldLabel.Text = 'Posição do Carrinho';
            app.x = uieditfield(app.panel_matriz_q, 'numeric');
            app.x.Value = 1;
            app.ngulodaHasteEditFieldLabel = uilabel(app.panel_matriz_q);
            app.ngulodaHasteEditFieldLabel.Text = 'Ângulo da Haste';
            app.alpha = uieditfield(app.panel_matriz_q, 'numeric');
            app.alpha.Value = 100;
            app.VelocidadedoCarrinhoEditFieldLabel = uilabel(app.panel_matriz_q);
            app.VelocidadedoCarrinhoEditFieldLabel.Text = 'Velocidade do Carrinho';
            app.dotx = uieditfield(app.panel_matriz_q, 'numeric');
            app.panel_filtragem = uibuttongroup(app.SimulaoTab);
            app.panel_filtragem.Enable = 'off';
            app.panel_filtragem.Title = 'Filtros';
            app.radio_filtro_kalman = uiradiobutton(app.panel_filtragem);
            app.radio_filtro_kalman.Enable = 'off';
            app.radio_filtro_kalman.Text = ' Kalman';
            app.radio_filtro_kalman.Value = true;
            app.radio_filtro_kalman_estendido = uiradiobutton(app.panel_filtragem);
            app.radio_filtro_kalman_estendido.Enable = 'off';
            app.radio_filtro_kalman_estendido.Text = 'Kalman Estendido';
            app.checkbox_incluir_controle = uicheckbox(app.SimulaoTab);
            app.checkbox_incluir_controle.ValueChangedFcn = createCallbackFcn(app, @checkbox_incluir_controleValueChanged, true);
            app.checkbox_incluir_controle.Text = 'Incluir controle - LQR';
            app.checkbox_incluir_controle.Value = true;
            app.panel_linearizacao = uibuttongroup(app.SimulaoTab);
            app.panel_linearizacao.Title = 'Linearização';
            app.checkbox_linearizado = uicheckbox(app.panel_linearizacao);
            app.checkbox_linearizado.ValueChangedFcn = createCallbackFcn(app, @checkbox_linearizadoValueChanged, true);
            app.checkbox_linearizado.Text = 'Linearizado';
            app.checkbox_linearizado.Value = true;
            app.checkbox_nao_linear = uicheckbox(app.panel_linearizacao);
            app.checkbox_nao_linear.ValueChangedFcn = createCallbackFcn(app, @checkbox_nao_linearValueChanged, true);
            app.checkbox_nao_linear.Text = 'Não-linear';
            app.panel_estimador = uipanel(app.SimulaoTab);
            app.panel_estimador.Enable = 'off';
            app.panel_estimador.Title = 'Condições Iniciais - Estimador';
            app.ci_estimador_x = uieditfield(app.panel_estimador, 'numeric');
            app.ci_estimador_x.Enable = 'off';
            app.ci_estimador_dotx_label = uilabel(app.panel_estimador);
            app.ci_estimador_dotx_label.Enable = 'off';
            app.ci_estimador_dotx_label.Text = 'Velocidade do Carrinho';
            app.ci_estimador_dotx = uieditfield(app.panel_estimador, 'numeric');
            app.ci_estimador_dotx.Enable = 'off';
            app.ci_estimador_alpha_label = uilabel(app.panel_estimador);
            app.ci_estimador_alpha_label.Enable = 'off';
            app.ci_estimador_alpha_label.Text = 'Ângulo da Haste';
            app.ci_estimador_alpha = uieditfield(app.panel_estimador, 'numeric');
            app.ci_estimador_alpha.Enable = 'off';
            app.ci_estimador_alpha.Value = 30;
            app.ci_estimador_dotalpha_label = uilabel(app.panel_estimador);
            app.ci_estimador_dotalpha_label.Enable = 'off';
            app.ci_estimador_dotalpha_label.Text = 'Velocidade da Haste';
            app.ci_estimador_dotalpha = uieditfield(app.panel_estimador, 'numeric');
            app.ci_estimador_dotalpha.Enable = 'off';
            app.ci_estimador_x_label = uilabel(app.panel_estimador);
            app.ci_estimador_x_label.Enable = 'off';
            app.ci_estimador_x_label.Text = 'Posição do Carrinho';
            app.CondiesIniciaisSistemaRealPanel = uipanel(app.SimulaoTab);
            app.CondiesIniciaisSistemaRealPanel.Title = 'Condições Iniciais: Sistema Real';
            app.PosiodoCarrinhoEditFieldLabel_2 = uilabel(app.CondiesIniciaisSistemaRealPanel);
            app.PosiodoCarrinhoEditFieldLabel_2.Text = 'Posição do Carrinho';
            app.ci_dotalpha = uieditfield(app.CondiesIniciaisSistemaRealPanel, 'numeric');
            app.VelocidadedaHasteEditFieldLabel_2 = uilabel(app.CondiesIniciaisSistemaRealPanel);
            app.VelocidadedaHasteEditFieldLabel_2.Text = 'Velocidade da Haste';
            app.ci_alpha = uieditfield(app.CondiesIniciaisSistemaRealPanel, 'numeric');
            app.ci_alpha.Value = 30;
            app.ngulodaHasteEditFieldLabel_2 = uilabel(app.CondiesIniciaisSistemaRealPanel);
            app.ngulodaHasteEditFieldLabel_2.Text = 'Ângulo da Haste';
            app.ci_dotx = uieditfield(app.CondiesIniciaisSistemaRealPanel, 'numeric');
            app.VelocidadedoCarrinhoEditFieldLabel_2 = uilabel(app.CondiesIniciaisSistemaRealPanel);
            app.VelocidadedoCarrinhoEditFieldLabel_2.Text = 'Velocidade do Carrinho';
            app.ci_x = uieditfield(app.CondiesIniciaisSistemaRealPanel, 'numeric');
            app.dropbox_filtragem = uidropdown(app.SimulaoTab);
            app.dropbox_filtragem.Items = {'Sem filtro', 'Com filtro', ''};
            app.dropbox_filtragem.ValueChangedFcn = createCallbackFcn(app, @dropbox_filtragemValueChanged, true);
            app.dropbox_filtragem.Value = 'Sem filtro';
            app.FiltragemDropDownLabel = uilabel(app.SimulaoTab);
            app.FiltragemDropDownLabel.Text = 'Filtragem';
            app.ConfigurodoTempoPanel = uipanel(app.SimulaoTab);
            app.ConfigurodoTempoPanel.Title = 'Configurção do Tempo';
            app.TempodeSimulaoLabel = uilabel(app.ConfigurodoTempoPanel);
            app.TempodeSimulaoLabel.Text = 'Tempo de Simulação';
            app.tempo_simulacao = uispinner(app.ConfigurodoTempoPanel);
            app.tempo_simulacao.Step = 5;
            app.tempo_simulacao.Limits = [0 100];
            app.tempo_simulacao.Value = 10;
            app.PerododeAmostragemLabel = uilabel(app.ConfigurodoTempoPanel);
            app.PerododeAmostragemLabel.Text = 'Período de Amostragem';
            app.periodo_amostragem = uieditfield(app.ConfigurodoTempoPanel, 'numeric');
            app.periodo_amostragem.Value = 0.01;
            app.PoloseZerosButton = uibutton(app.SimulaoTab, 'push');
            app.PoloseZerosButton.ButtonPushedFcn = createCallbackFcn(app, @PoloseZerosButtonPushed, true);
            app.PoloseZerosButton.Text = 'Polos e Zeros';
            app.btn_visualizar_ganho_kalman = uibutton(app.SimulaoTab, 'push');
            app.btn_visualizar_ganho_kalman.ButtonPushedFcn = createCallbackFcn(app, @btn_visualizar_ganho_kalmanButtonPushed, true);
            app.btn_visualizar_ganho_kalman.Text = 'Ganho de Kalman';
            app.mostrar_janela = uicheckbox(app.SimulaoTab);
            app.mostrar_janela.Text = 'Mostrar Resultado em janela separada';
            app.RudosTab = uitab(app.TabGroup);
            app.RudosTab.Title = 'Ruídos';
            app.GridLayout4 = uigridlayout(app.RudosTab);
            app.grafico_ruido_v = uiaxes(app.GridLayout4);
            title(app.grafico_ruido_v, 'Ruído - v')
            xlabel(app.grafico_ruido_v, 'Ocorrências')
            ylabel(app.grafico_ruido_v, 'Desvio Padrão - \sigma')
            app.grafico_fdp_v = uiaxes(app.GridLayout4);
            title(app.grafico_fdp_v, 'Função Densidade de Probabilidades - v')
            xlabel(app.grafico_fdp_v, 'Variável Aleatória')
            ylabel(app.grafico_fdp_v, 'Função')
            app.grafico_ruido_w = uiaxes(app.GridLayout4);
            title(app.grafico_ruido_w, 'Ruído - w')
            xlabel(app.grafico_ruido_w, 'Ocorrências')
            ylabel(app.grafico_ruido_w, 'Desvio Padrão - \sigma')
            app.grafico_fdp_w = uiaxes(app.GridLayout4);
            title(app.grafico_fdp_w, 'Função Densidade de Probabilidades - w')
            xlabel(app.grafico_fdp_w, 'Variável Aleatória')
            ylabel(app.grafico_fdp_w, 'Função')
            app.grafico_fdp_w.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.RudonosSensoresvPanel = uipanel(app.GridLayout4);
            app.RudonosSensoresvPanel.Title = 'Ruído nos Sensores - v';
            app.ruido_label_desvio_padrao_v = uilabel(app.RudonosSensoresvPanel);
            app.ruido_label_desvio_padrao_v.Text = 'Desvio Padrão:';
            app.ruido_label_variancia_v = uilabel(app.RudonosSensoresvPanel);
            app.ruido_label_variancia_v.Text = 'Variância:';
            app.MedidadeDispersoDropDownLabel = uilabel(app.RudonosSensoresvPanel);
            app.MedidadeDispersoDropDownLabel.Text = 'Medida de Dispersão';
            app.drop_v = uidropdown(app.RudonosSensoresvPanel);
            app.drop_v.Items = {'Variância', 'Desvio Padrão'};
            app.drop_v.Value = 'Variância';
            app.ValorEditFieldLabel = uilabel(app.RudonosSensoresvPanel);
            app.ValorEditFieldLabel.Text = 'Valor';
            app.valor_v = uieditfield(app.RudonosSensoresvPanel, 'numeric');
            app.valor_v.Value = 0.005;
            app.variancia_v = uilabel(app.RudonosSensoresvPanel);
            app.variancia_v.Text = '';
            app.desvio_v = uilabel(app.RudonosSensoresvPanel);
            app.desvio_v.Text = '';
            app.GerarRuidoButton_v = uibutton(app.RudonosSensoresvPanel, 'push');
            app.GerarRuidoButton_v.ButtonPushedFcn = createCallbackFcn(app, @GerarRuidoButton_vPushed, true);
            app.GerarRuidoButton_v.Text = 'Gerar Ruído';
            app.ruido_label_media_v = uilabel(app.RudonosSensoresvPanel);
            app.ruido_label_media_v.Text = 'Média';
            app.spinner_media_v = uispinner(app.RudonosSensoresvPanel);
            app.spinner_media_v.Limits = [0 100];
            app.spinner_media_v.ValueDisplayFormat = '%.0f';
            app.checkbox_multiplos_pdf_v = uicheckbox(app.RudonosSensoresvPanel);
            app.checkbox_multiplos_pdf_v.ValueChangedFcn = createCallbackFcn(app, @checkbox_multiplos_pdf_vValueChanged, true);
            app.checkbox_multiplos_pdf_v.Text = 'Múltiplos PDF';
            app.checkbox_utilizar_simulacao_v = uicheckbox(app.RudonosSensoresvPanel);
            app.checkbox_utilizar_simulacao_v.ValueChangedFcn = createCallbackFcn(app, @checkbox_utilizar_simulacao_vValueChanged, true);
            app.checkbox_utilizar_simulacao_v.Text = 'Utilizar na Simulação';
            app.RudonoSistemawPanel = uipanel(app.GridLayout4);
            app.RudonoSistemawPanel.Title = 'Ruído no Sistema - w';
            app.ruido_label_desvio_padrao_w = uilabel(app.RudonoSistemawPanel);
            app.ruido_label_desvio_padrao_w.Text = 'Desvio Padrão:';
            app.ruido_label_variancia_w = uilabel(app.RudonoSistemawPanel);
            app.ruido_label_variancia_w.Text = 'Variância:';
            app.MedidadeDispersoDropDownLabel_2 = uilabel(app.RudonoSistemawPanel);
            app.MedidadeDispersoDropDownLabel_2.Text = 'Medida de Dispersão';
            app.drop_w = uidropdown(app.RudonoSistemawPanel);
            app.drop_w.Items = {'Variância', 'Desvio Padrão'};
            app.drop_w.Value = 'Variância';
            app.ValorEditFieldLabel_2 = uilabel(app.RudonoSistemawPanel);
            app.ValorEditFieldLabel_2.Text = 'Valor';
            app.valor_w = uieditfield(app.RudonoSistemawPanel, 'numeric');
            app.valor_w.Value = 0.001;
            app.variancia_w = uilabel(app.RudonoSistemawPanel);
            app.variancia_w.Text = '';
            app.desvio_w = uilabel(app.RudonoSistemawPanel);
            app.desvio_w.Text = '';
            app.GerarRuidoButton = uibutton(app.RudonoSistemawPanel, 'push');
            app.GerarRuidoButton.ButtonPushedFcn = createCallbackFcn(app, @GerarRuidoButtonPushed, true);
            app.GerarRuidoButton.Text = 'Gerar Ruído';
            app.ruido_label_media_w = uilabel(app.RudonoSistemawPanel);
            app.ruido_label_media_w.Text = 'Média';
            app.spinner_media_w = uispinner(app.RudonoSistemawPanel);
            app.spinner_media_w.Limits = [0 100];
            app.spinner_media_w.ValueDisplayFormat = '%.0f';
            app.checkbox_multiplos_pdf_w = uicheckbox(app.RudonoSistemawPanel);
            app.checkbox_multiplos_pdf_w.ValueChangedFcn = createCallbackFcn(app, @checkbox_multiplos_pdf_wValueChanged, true);
            app.checkbox_multiplos_pdf_w.Text = 'Múltiplos PDF';
            app.checkbox_utilizar_simulacao_w = uicheckbox(app.RudonoSistemawPanel);
            app.checkbox_utilizar_simulacao_w.ValueChangedFcn = createCallbackFcn(app, @checkbox_utilizar_simulacao_wValueChanged, true);
            app.checkbox_utilizar_simulacao_w.Text = 'Utilizar na Simulação';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Copy_of_aplicativo_pendulo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end